classdef Project_1_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Menu                      matlab.ui.container.Menu
        InitializeUnoMenu         matlab.ui.container.Menu
        EchoPinEditField          matlab.ui.control.EditField
        EchoPinEditFieldLabel     matlab.ui.control.Label
        TriggerPinEditField       matlab.ui.control.EditField
        TriggerPinEditFieldLabel  matlab.ui.control.Label
        PortEditField             matlab.ui.control.EditField
        PortEditFieldLabel        matlab.ui.control.Label
        Measure3secondsButton     matlab.ui.control.Button
        ResetButton               matlab.ui.control.Button
        Lamp                      matlab.ui.control.Lamp
        LampLabel                 matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
    Uno = [];
    ultraS = [];
    distanceData = [];
    done = false;
    
    index = 1;
    x = [];
    y = [];
    doneReading = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: InitializeUnoMenu
        function StartUno(app, event)
            try
                port = app.PortEditField.Value;
                triggerPin = app.TriggerPinEditField.Value;
                echoPin = app.EchoPinEditField.Value;
                
                app.Uno = arduino(port, 'Uno', 'Libraries', 'Ultrasonic');
                app.ultraS = app.Uno.ultrasonic(triggerPin, echoPin);
                
                app.ResetGraph();
                
                app.done = true;
                msgbox ('Ardiono and Ultrasound ready');
            catch 
                msgbox('Port failed or incorrect pins');
                return;
                
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.ResetGraph();
        end

        % Button pushed function: Measure3secondsButton
        function Measure3secondsButtonPushed(app, event)
            tic
            while tic < 3 seconds
            app.distanceData = app.Uno.ultrasound.readDistance();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.InitializeUnoMenu = uimenu(app.Menu);
            app.InitializeUnoMenu.MenuSelectedFcn = createCallbackFcn(app, @StartUno, true);
            app.InitializeUnoMenu.Text = 'InitializeUno';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Measure3secondsButton = uibutton(app.UIFigure, 'push');
            app.Measure3secondsButton.ButtonPushedFcn = createCallbackFcn(app, @Measure3secondsButtonPushed, true);
            app.Measure3secondsButton.Text = 'Measure (3 seconds)';
            app.PortEditFieldLabel = uilabel(app.UIFigure);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.UIFigure, 'text');
            app.TriggerPinEditFieldLabel = uilabel(app.UIFigure);
            app.TriggerPinEditFieldLabel.Text = 'Trigger Pin';
            app.TriggerPinEditField = uieditfield(app.UIFigure, 'text');
            app.EchoPinEditFieldLabel = uilabel(app.UIFigure);
            app.EchoPinEditFieldLabel.Text = 'Echo Pin';
            app.EchoPinEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project_1_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end