classdef UpdatedProject_1_appmp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        GoodDataLampLabel         matlab.ui.control.Label
        GoodDataLamp              matlab.ui.control.Lamp
        ResetGraphButton          matlab.ui.control.Button
        Measure3secondsButton     matlab.ui.control.Button
        PortEditFieldLabel        matlab.ui.control.Label
        PortEditField             matlab.ui.control.EditField
        TriggerPinEditFieldLabel  matlab.ui.control.Label
        TriggerPinEditField       matlab.ui.control.EditField
        EchoPinEditFieldLabel     matlab.ui.control.Label
        EchoPinEditField          matlab.ui.control.EditField
        BadDataLampLabel          matlab.ui.control.Label
        BadDataLamp               matlab.ui.control.Lamp
        ImportDataButton          matlab.ui.control.Button
        InitializeArduinoButton   matlab.ui.control.Button
        RecordDataButton          matlab.ui.control.StateButton
        QuickInitButton           matlab.ui.control.Button
    end


    properties (Access = public)
        % Empty variables that hardware or data can be assigned to for easy
        % access and storage

        Uno = [];
        ultraS = [];
        distanceData = [];
        timeData = [];
        buttonOn = 0;
        file1 = [];
        line = [];
        line2 = [];
        line3 = [];
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitializeArduinoButton
        function StartUno(app, event)
            try
                port = app.PortEditField.Value;                     % Taking user inputs and saving them to easier-to-use variables
                triggerPin = app.TriggerPinEditField.Value;
                echoPin = app.EchoPinEditField.Value;

                app.Uno = arduino(port, 'Uno', 'Libraries', 'Ultrasonic'); % assigning the arduino to the 'Uno' array for easy access

            catch
                msgbox('Arduino failed');           % msgboxes are put in a way so that if initialization fails, the user can determine where it failed (Arduino or sensor)
            end

            try
                app.ultraS = app.Uno.ultrasonic(triggerPin, echoPin);       % assigning the ultrasonic sensor to 'ultraS'

                msgbox ('Ardiono and Ultrasound ready');
            catch
                msgbox('Arduino Ready BUT Ultrasound failed');
                return;

            end
        end

        % Button pushed function: ResetGraphButton
        function ResetGraphButtonPushed(app, event)
            delete(app.line);           % Deletes the curve plotted when measuring data through the sensor
            delete(app.line2);          % Deletes the curve loaded from previously measured curve
            delete(app.line3);          % Deletes the curve loaded from previously measured curve
        end

        % Button pushed function: Measure3secondsButton
        function Measure3secondsButtonPushed(app, event)

           
            y = [inf, 1];               % Initializing the y and t arrays. They have infinite rows and 1 column
            t = [inf, 1];
            
            y2 = [inf, 1];               % Initializing the y and t arrays. They have infinite rows and 1 column
            t2 = [inf, 1];
            
            hold (app.UIAxes, 'on')
            count = 1;                  % Making a counting variable. This is needed for saving the data to the array and can be used to count number of iterations
            count2 = 1;                  % Making a counting variable. This is needed for saving the data to the array and can be used to count number of iterations
      
            tic
            while (toc < 3)                                           % keeps the loop going for 3 seconds
                app.distanceData = app.ultraS.readDistance();         % assigning ultrasound readings to the distanceData array
                app.timeData = toc;                                   % assigning the time at which each reading is taken to the timeData array
                
                y(count,1) = [app.distanceData];                      % Saving sensor data into the 'y' array. For every row, the sensor data is saved per iteration
                t(count,1) = [app.timeData];                          % Same as above line except for time data.
               
                if (app.distanceData == inf)
                
                else
                    
                y2(count2,1) = [app.distanceData];                      % Saving sensor data into the 'y' array. For every row, the sensor data is saved per iteration
                t2(count2,1) = [app.timeData];                          % Same as above line except for time data.
                count2 = count2 + 1;                                    % incrementing the count as the loop is repeated
 
                end
                                count = count + 1;                                    % incrementing the count as the loop is repeated
 

                % plot(app.UIAxes,toc, app.ultraS.readDistance())      % plotting the distance and time values onto the app's graph 
                % plot(app.UIAxes,app.timeData, app.distanceData)      % plotting the distance and time values onto the app's graph in real time
                % plot(app.timeData,app.distanceData);


            end

            y(:,2) = [];      % This line was necessary because for some reason, the array produces a second column of zeros with 
                              % the first row being '1' and making the data unusable. This line deletes the second column by equating it to empty brackets 
            t(:,2) = [];      % Same as line 102
       
            y2(:,2) = [];      % This line was necessary because for some reason, the array produces a second column of zeros with 
                              % the first row being '1' and making the data unusable. This line deletes the second column by equating it to empty brackets 
            t2(:,2) = [];      % Same as line 102
            
            
            delete(app.line2); % Same as line 112
            app.line2 = plot(app.UIAxes,t, y);      % plotting the distance and time values onto the app's graph / unfiltered


          delete(app.line3); % I discovered that if plotting more than once without a handle or name for the plot, 
                               % the old plot becomes impossible to erase because its name is gone and can't be referenced
                               % Therefore, one must name the plot and
                               % delete it before making a new one, else it
                               % will stay permanently


            medfilt1(y2, 9)                   
            app.line3 = plot(app.UIAxes,t2, y2);      % plotting the distance and time values onto the app's graph / filtered


% plot(app.UIAxes,app.timeData,app.distanceData);

            if (app.buttonOn)

                save ('PendulumData', 't', 'y'); % A save function that becomes enabled when the 'Record' switch is turned on. This then becomes the data that is used when 'Import Data' is pushed.
                save ('PendulumDataF', 't2', 'y2'); % A save function that becomes enabled when the 'Record' switch is turned on. This then becomes the data that is used when 'Import Data' is pushed.

            end
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)

            load PendulumData.mat;              % Loads the saved data from a previously measured graph
            delete(app.line);                   % Same thing explained in line 106
            app.line = plot(app.UIAxes,t,y)     % Two different plot names were necessary in order to differentiate between the measured curve and loaded curve.
                                                % Only two curves can be displayed at once
            
            
            % Failed attempt at making a more user friendly interface below
            
%             [file,path] = uigetfile; % uigetfile makes the file explorer pop up
%             SelectedFile = fullfile(path,file); % fullfile gets the entire file directory for a file
%             if isequal(file,0)                   % if no path is recieved, the user must have exitted the file explorer pop up
%                 msgbox('User closed the File Explorer');
%             else
%                 disp(['User selected ', file]);
%                 
% 
%                 fileID = fopen(SelectedFile, 'r'); % the 'r' stand for 'read'
%                 sizeA = [inf 2];
%                 A = fscanf(fileID,'%f', sizeA); % fscanf reads the file 'fileID' and writes its contents into the array 'A' in '%f' form or
%                 % floating point form. the array 'A'
%                 % will be in the form of 'sizeA' or
%                 % infinite
%                 % rows and 2 columns
%                 x = A(:,1);
%                 y = A(:,2);
%                 hold (app.UIAxes, 'on')
%                 plot(app.UIAxes,x,y)
%                 plot(x,y)
%                 
%             end
% marissa code for led, 
% void setup() {                   // void will allow function to run until reset
% 
% pinMode (LED_BUILTIN, OUTPUT) 
% %        }
%% if there is no reset button you can use code
% void loop() {
% digitalWrite(LED_BUILTIN, HIGH);
% delay(1000);
% digitalWrite(LED_BUILTIN, LOW);
% delay(1000);
%    }
% 
% in main loop next line of code
% digitalWrite(LED_BUILTIN, HIGH); // supplies 5v to LED, LED ON
% digitalWrite(LED_BUILTIN, LOW);// supplies -5v to LED, LED OFF
% additional code below for one led located at 13 pin 

% void setup()
% {
% pinMode(13, OUTPUT);
% }
% void loop()
% {
% digitalWrite(13,HIGH)
% delay(1000);
% digitalWrite(13,LOW);
% delay(1000);
% }

        end

        % Callback function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: RecordDataButton
        function RecordDataButtonValueChanged(app, event)
            app.buttonOn = app.RecordDataButton.Value; % A function that is turned on or off. Used for determining when to save a measured graph

        end

        % Button pushed function: QuickInitButton
        function QuickInitButtonPushed(app, event)
           try
               % Developer function to make the project faster to test.
               % Won't be used in final product

                app.Uno = arduino('COM3', 'Uno', 'Libraries', 'Ultrasonic'); % assigning the arduino to the 'Uno' array for easy access

            catch
                msgbox('Arduino failed');
            end

            try
                app.ultraS = app.Uno.ultrasonic("D4", "D3");       % assigning the ultrasonic sensor to 'ultraS'

                msgbox ('Ardiono and Ultrasound ready');
            catch
                msgbox('Arduino Ready BUT Ultrasound failed');
                return;

            end
%             9led pin set up 
% int t = 40;
% int rnd =5;
% int pat1t =75;
% 
% void setup()
% {
%    for(int i=3; i<=12; i++) 
%    pinMode(i,OUTPUT);
% }
% 
% void loop(){
% 
%    for(int i=0; i<=rnd; i++) {
%    pat1();}
%    for(int i=0; i<=rnd; i++){ 
%    pat2();}
%    for(int i=0; i<=rnd; i++) {
%    pat3();}
%    for(int i=0; i<=rnd; i++) {
%    pat4();}
%    for(int i=0; i<=rnd; i++){ 
%    pat5();}
%    for(int i=0; i<=rnd; i++) {
%    pat6();}
%    for(int i=0; i<=rnd; i++) {
%    pat7();}
%    for(int i=0; i<=rnd; i++) {
%    pat8();}
%    for(int i=0; i<=rnd; i++) {
%    pat9();}
%   
%   
% }
%   
% void pat1(){ 
%     for(int i=3; i<=12; i++) {
%       digitalWrite(i,HIGH);
%       delay(pat1t);
%       digitalWrite(i,LOW);
%  
%     }
%     
%     for(int i=11; i>=4; i--) {
%       digitalWrite(i,HIGH);
%       delay(pat1t);
%       digitalWrite(i,LOW);
%     }
% }   
% void pat2(){
%      for(int i=3; i<=12; i++) {
%       digitalWrite(i,HIGH);
%       digitalWrite(i-1,HIGH);
%       digitalWrite(i+1,HIGH);
%       delay(100);
%       digitalWrite(i,LOW);
%       digitalWrite(i-1,LOW);
%       digitalWrite(i+1,LOW);
%     }
%     
%     for(int i=11; i>=4; i--) {
%       digitalWrite(i,HIGH);
%       digitalWrite(i-1,HIGH);
%       digitalWrite(i+1,HIGH);
%       delay(100);
%       digitalWrite(i,LOW);
%       digitalWrite(i-1,LOW);
%       digitalWrite(i+1,LOW);
%     }
% }
% void pat3(){
%   for(int i=3; i<=12; i=i+2) {
%       digitalWrite(i,HIGH);
%       delay(100);
%       digitalWrite(i,LOW);
%  
%     }
%     
%     for(int i=12; i>=3; i=i-2) {
%       digitalWrite(i,HIGH);
%       delay(100);
%       digitalWrite(i,LOW);
%     }       
% }
% void pat4(){   
%       for(int i=3; i<=12; i++) {
%       digitalWrite(i,HIGH);
%       delay(100);
%     }
%     
%     for(int i=12; i>=2; i--) {
%       digitalWrite(i,HIGH);
%       delay(100);
%       digitalWrite(i,LOW);
%     }
% }
% void pat5(){
%      for(int i=3; i<=12; i++) {
%       digitalWrite(i,HIGH);
%       }
%     delay(100);
%     for(int i=3; i<=12; i++) {
%       digitalWrite(i,LOW);
%       }
%     delay(100);  
% }
% void pat6(){
%        for(int i=3; i<=8; i++) {
%       digitalWrite(i,HIGH);
%       }
%     for(int i=8; i<=12; i++) {
%       digitalWrite(i,LOW);
%       }
%     delay(200);
%     for(int i=3; i<=8; i++) {
%       digitalWrite(i,LOW);
%       }
%     for(int i=8; i<=12; i++) {
%       digitalWrite(i,HIGH);
%       }
%     delay(200);
% }
% void pat7(){
%        for(int i=3; i<=12; i=i+2) {
%       digitalWrite(i,HIGH);
%       }
%     for(int i=4; i<=12; i=i+2) {
%       digitalWrite(i,LOW);
%       }
%     delay(200);
%      for(int i=3; i<=12; i=i+2) {
%       digitalWrite(i,LOW);
%       }
%     for(int i=4; i<=12; i=i+2) {
%       digitalWrite(i,HIGH);
%       }
%     delay(200);
% }
% void pat8(){
%     digitalWrite(7,HIGH);
%     digitalWrite(8,HIGH);
%     delay(t);
%     digitalWrite(7,LOW);
%     digitalWrite(8,LOW);
%     delay(t);
%     digitalWrite(6,HIGH);
%     digitalWrite(9,HIGH);
%     delay(t);
%     digitalWrite(6,LOW);
%     digitalWrite(9,LOW);
%     delay(t);
%     digitalWrite(5,HIGH);
%     digitalWrite(10,HIGH);
%     delay(t);
%     digitalWrite(5,LOW);
%     digitalWrite(10,LOW);
%     delay(t); 
%     digitalWrite(4,HIGH);
%     digitalWrite(11,HIGH);
%     delay(t);
%     digitalWrite(4,LOW);
%     digitalWrite(11,LOW);
%     delay(t);
%     digitalWrite(3,HIGH);
%     digitalWrite(12,HIGH);
%     delay(t);
%     digitalWrite(3,LOW);
%     digitalWrite(12,LOW);
%     delay(t);
%      digitalWrite(4,HIGH);
%     digitalWrite(11,HIGH);
%     delay(t);
%     digitalWrite(4,LOW);
%     digitalWrite(11,LOW);
%     delay(t);
%     digitalWrite(5,HIGH);
%     digitalWrite(10,HIGH);
%     delay(t);
%     digitalWrite(5,LOW);
%     digitalWrite(10,LOW);
%     delay(t);
%     digitalWrite(6,HIGH);
%     digitalWrite(9,HIGH);
%     delay(t);
%     digitalWrite(6,LOW);
%     digitalWrite(9,LOW);
%     delay(t);       
% }    
% 
% void pat9(){ 
%     for(int i=3; i<=12; i++) {
%       digitalWrite(i,HIGH);
%     }
%      for(int i=3; i<=12; i++) {
%       digitalWrite(i,LOW);
%       delay(100);
%       digitalWrite(i,HIGH);
%     }
%     for(int i=11; i>=4; i--) {
%       digitalWrite(i,LOW);
%       delay(100);
%       digitalWrite(i,HIGH);
%     }
%   
% }  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Pendulum Distance ')
            xlabel(app.UIAxes, 'Time (seconds)')
            ylabel(app.UIAxes, 'Distance (meters)')
            zlabel(app.UIAxes, 'Z')
            app.GoodDataLampLabel = uilabel(app.UIFigure);
            app.GoodDataLampLabel.Text = 'Good Data';
            app.GoodDataLamp = uilamp(app.UIFigure);
            app.ResetGraphButton = uibutton(app.UIFigure, 'push');
            app.ResetGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ResetGraphButtonPushed, true);
            app.ResetGraphButton.Text = 'Reset Graph';
            app.Measure3secondsButton = uibutton(app.UIFigure, 'push');
            app.Measure3secondsButton.ButtonPushedFcn = createCallbackFcn(app, @Measure3secondsButtonPushed, true);
            app.Measure3secondsButton.Text = 'Measure (3 seconds)';
            app.PortEditFieldLabel = uilabel(app.UIFigure);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.UIFigure, 'text');
            app.TriggerPinEditFieldLabel = uilabel(app.UIFigure);
            app.TriggerPinEditFieldLabel.Text = 'Trigger Pin';
            app.TriggerPinEditField = uieditfield(app.UIFigure, 'text');
            app.EchoPinEditFieldLabel = uilabel(app.UIFigure);
            app.EchoPinEditFieldLabel.Text = 'Echo Pin';
            app.EchoPinEditField = uieditfield(app.UIFigure, 'text');
            app.BadDataLampLabel = uilabel(app.UIFigure);
            app.BadDataLampLabel.Text = 'Bad Data';
            app.BadDataLamp = uilamp(app.UIFigure);
            app.ImportDataButton = uibutton(app.UIFigure, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.InitializeArduinoButton = uibutton(app.UIFigure, 'push');
            app.InitializeArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @StartUno, true);
            app.InitializeArduinoButton.Text = {'Initialize Arduino'; ''};
            app.RecordDataButton = uibutton(app.UIFigure, 'state');
            app.RecordDataButton.ValueChangedFcn = createCallbackFcn(app, @RecordDataButtonValueChanged, true);
            app.RecordDataButton.Text = 'Record Data';
            app.QuickInitButton = uibutton(app.UIFigure, 'push');
            app.QuickInitButton.ButtonPushedFcn = createCallbackFcn(app, @QuickInitButtonPushed, true);
            app.QuickInitButton.Text = 'Quick Init';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UpdatedProject_1_appmp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end