classdef Project_2_getting_period < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ECE3040Project1UIFigure    matlab.ui.Figure
        LoadMenu                   matlab.ui.container.Menu
        Data1Menu                  matlab.ui.container.Menu
        Data2Menu                  matlab.ui.container.Menu
        Data3Menu                  matlab.ui.container.Menu
        EditField3                 matlab.ui.control.NumericEditField
        EditField3Label            matlab.ui.control.Label
        EditField2                 matlab.ui.control.NumericEditField
        EditField2Label            matlab.ui.control.Label
        EditField                  matlab.ui.control.NumericEditField
        EditFieldLabel             matlab.ui.control.Label
        DropDown                   matlab.ui.control.DropDown
        DropDownLabel              matlab.ui.control.Label
        RecordData2Button          matlab.ui.control.StateButton
        RecordData3Button          matlab.ui.control.StateButton
        Lamp_4                     matlab.ui.control.Lamp
        Lamp_4Label                matlab.ui.control.Label
        Lamp_3                     matlab.ui.control.Lamp
        Lamp_3Label                matlab.ui.control.Label
        Lamp_2                     matlab.ui.control.Lamp
        Lamp_2Label                matlab.ui.control.Label
        Lamp                       matlab.ui.control.Lamp
        Label                      matlab.ui.control.Label
        LoadedFilteredDataLabel    matlab.ui.control.Label
        LoadedRawDataLabel         matlab.ui.control.Label
        MeasuredFilteredDataLabel  matlab.ui.control.Label
        MeasuredRawDataLabel       matlab.ui.control.Label
        QuickInitButton            matlab.ui.control.Button
        RecordData1Button          matlab.ui.control.StateButton
        InitializeArduinoButton    matlab.ui.control.Button
        BadDataLamp                matlab.ui.control.Lamp
        BadDataLampLabel           matlab.ui.control.Label
        EchoPinEditField           matlab.ui.control.EditField
        EchoPinEditFieldLabel      matlab.ui.control.Label
        TriggerPinEditField        matlab.ui.control.EditField
        TriggerPinEditFieldLabel   matlab.ui.control.Label
        PortEditField              matlab.ui.control.EditField
        PortEditFieldLabel         matlab.ui.control.Label
        Measure7secondsButton      matlab.ui.control.Button
        ResetGraphButton           matlab.ui.control.Button
        GoodDataLamp               matlab.ui.control.Lamp
        GoodDataLampLabel          matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

%************************************************************************%
%                     Project 1 (Theory of Pendulums)                    %
%                                                                        %
%    Names: Alfarook Saleh %60, Marissa Proctor %20, Hussein Bazzi %20   %
%                    Class: ECE 3040 Numerical Methods                   %
%                        Professor: Abhilash Pandya                      %
%                           Date: 10/1/2021                              %
%                                                                        %
%************************************************************************%


    properties (Access = public)
       
        
        % Marissa: Empty variables that hardware or data can be assigned to for easy
        % access and storage

        Uno = [];
        ultraS = [];
        distanceData = [];
        timeData = [];
        buttonOn = 0;
        buttonOn2 = 0;
        buttonOn3 = 0;
        line1 = [];
        line2 = [];
        line3 = [];
        line4 = [];

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitializeArduinoButton
        function StartUno(app, event)
            try
                port = app.PortEditField.Value;                     % Marissa: Taking user inputs and saving them to easier-to-use variables
                triggerPin = app.TriggerPinEditField.Value;
                echoPin = app.EchoPinEditField.Value;

                app.Uno = arduino(port, 'Uno', 'Libraries', 'Ultrasonic'); % Marissa: Assigning the arduino to the 'Uno' array for easy access

            catch
                
                msgbox('Arduino failed');           % Marissa: Msgboxes are put in a way so that if initialization fails, the user can determine where it failed (Arduino or sensor)
           
            end

            try
                app.ultraS = app.Uno.ultrasonic(triggerPin, echoPin);       % Marissa: Assigning the ultrasonic sensor to 'ultraS'

                writeDigitalPin(app.Uno, 'D7', 1);                          % Marissa: Activating pins D7 and D6 which turns on the connected LEDs
                writeDigitalPin(app.Uno, 'D6', 1);
                    
                msgbox ('Ardiono and Ultrasound ready');
                
            catch
                
                msgbox('Arduino Ready BUT Ultrasound failed');
                
                return;

            end

        end

        % Button pushed function: Measure7secondsButton
        function Measure7secondsButtonPushed(app, event)

            y = [];                % Alfarook: Initializing the y and t arrays. 
            t = [];

            y2 = [];               % Alfarook: Initializing the y2 and t2 arrays. 
            t2 = [];
            
            hold (app.UIAxes, 'on')     % Alfarook: This function makes everything graphed onto the UIAxes figure stay put instead of resetting when a new line is plotted

            count = 1;                  % Alfarook: Making a counting variable. This is needed for saving the data to the array and can be used to count number of iterations
            count2 = 1;                 % Alfarook: Making a counting variable. This is needed for saving the data to the array and can be used to count number of iterations

            tic
            
            while (toc < 7)                                           % Alfarook: keeps the loop going for 3 seconds
                app.distanceData = app.ultraS.readDistance();         % Alfarook: assigning ultrasound readings to the distanceData array
                app.timeData = toc;                                   % Alfarook: assigning the time at which each reading is taken to the timeData array

                y(count) = [app.distanceData];                      % Alfarook: Saving sensor data into the 'y' array.
                t(count) = [app.timeData];                          % Alfarook: Same as above line except for time data.

                if (app.distanceData == inf)                        % Alfarook: An if function that filters out 'inf' values by only saving data that isn't an 'inf' value

                else

                    y2(count2) = [app.distanceData];                      % Alfarook: Saving sensor data into the 'y2' array.
                    t2(count2) = [app.timeData];                          % Alfarook: Same as above line except for time data.
                    count2 = count2 + 1;                                    % Alfarook: Incrementing the count as the loop is repeated

                end

                
                if (app.distanceData > 0.3)                         % Alfarook: An if function that determines if the acquired distance data is within a good range (0.3 meters)
                    writeDigitalPin(app.Uno, 'D7', 1);              % or not and lights LEDs correspondingly
                    writeDigitalPin(app.Uno, 'D6', 0);
                    app.BadDataLamp.Enable = 'on';
                    app.GoodDataLamp.Enable = 'off';
                else
                    writeDigitalPin(app.Uno, 'D7', 0);
                    writeDigitalPin(app.Uno, 'D6', 1);
                    app.BadDataLamp.Enable = 'off';
                    app.GoodDataLamp.Enable = 'on';
                end


                count = count + 1;                                  % Alfarook: Incrementing the count as the loop is repeated

                
                delete(app.line1);  % Alfarook: If plotting more than once without a handle or name for the plot,
                                    % the old plot becomes impossible to erase because its name is gone and can't be referenced
                                    % Therefore, one must name the plot and
                                    % delete it before making a new one else it will stay permanently
                                     

                app.line1 = plot(app.UIAxes,t, y, 'b');      % Alfarook: Plotting the raw distance and time values onto the app's graph with the color blue and naming it 'app.line1' for future reference

            end
            
            writeDigitalPin(app.Uno, 'D7', 0);
            writeDigitalPin(app.Uno, 'D6', 0);
            app.BadDataLamp.Enable = 'off';
            app.GoodDataLamp.Enable = 'off';

            y2 = medfilt1(y2, 3);                            % Alfarook: Filtering the data to remove spikes using medfilt1. Takes the median of 3 entries of data
            
            delete(app.line2);                               % Alfarook: Same as line 160
           
            app.line2 = plot(app.UIAxes,t2, y2, 'g--');      % Alfarook: Plotting the filtered distance and time values onto the app's graph with a dashed green line and naming it 'app.line2' for future reference


            if (app.buttonOn)

                save ('PendulumData', 't', 'y');     % Hussein:  A save function that becomes enabled when the 'Record Data 1' switch is turned on. This then becomes the raw data that is used when
                                                     % 'Pendulum Data (6in.)' is pushed in the 'load' menu.
                                                 
                save ('PendulumDataF', 't2', 'y2');  % Hussein:  A save function that becomes enabled when the 'Record Data 1' switch is turned on. This then becomes the filtered data that is used when
                                                     % 'Pendulum Data (6in.)' is pushed in the 'load' menu.
            end
            if (app.buttonOn2)

                save ('PendulumData2', 't', 'y');    % Hussein:  A save function that becomes enabled when the 'Record Data 2' switch is turned on. This then becomes the raw data that is used when
                                                     % 'Pendulum Data (1ft.)' is pushed in the 'load' menu.
                
                save ('PendulumData2F', 't2', 'y2'); % Hussein:  A save function that becomes enabled when the 'Record Data 2' switch is turned on. This then becomes the filtered data that is used when
                                                     % 'Pendulum Data (1ft.)' is pushed in the 'load' menu.
            end
            if (app.buttonOn3)

                save ('PendulumData3', 't', 'y');    % Hussein:  A save function that becomes enabled when the 'Record Data 3' switch is turned on. This then becomes the raw data that is used when
                                                     % 'Pendulum Data (1ft. 6in.)' is pushed in the 'load' menu.
                
                save ('PendulumData3F', 't2', 'y2'); % Hussein:  A save function that becomes enabled when the 'Record Data 3' switch is turned on. This then becomes the filtered data that is used when
                                                     % 'Pendulum Data (1ft. 6in.)' is pushed in the 'load' menu.

            end

        end

        % Button pushed function: ResetGraphButton
        function ResetGraphButtonPushed(app, event)
            delete(app.line1);          % Hussein: Deletes the raw curve plotted when measuring data through the sensor
            delete(app.line2);          % Hussein: Deletes the filtered curve plotted when measuring data through the sensor
            delete(app.line3);          % Hussein: Deletes the raw curve loaded from previously measured curve
            delete(app.line4);          % Hussein: Deletes the filtered curve loaded from previously measured curve
        end

        % Menu selected function: Data1Menu
        function Data1MenuSelected(app, event)
            load PendulumData.mat;              % Hussein: Loads the saved raw data from a previously measured graph
            load PendulumDataF.mat;             % Hussein: Loads the saved filtered data from a previously measured graph
            hold (app.UIAxes, 'on')
            delete(app.line3);                   % Hussein: Same thing explained in line 160
            app.line3 = plot(app.UIAxes,t,y, 'm' );     % Hussein: Different plot names were necessary in order to differentiate between the 4 graphed curves. This curve is colored magenta
            delete(app.line4);
            app.line4 = plot(app.UIAxes,t2,y2, 'c--');  % Hussein: This curve is colored cyan and is dashed
        end

        % Menu selected function: Data2Menu
        function Data2MenuSelected(app, event)
            load PendulumData2.mat;              % Hussein: Loads the saved raw data from a previously measured graph
            load PendulumData2F.mat;             % Hussein: Loads the saved filtered data from a previously measured graph
            hold (app.UIAxes, 'on')
            delete(app.line3);                   % Hussein: Same thing explained in line 160
            app.line3 = plot(app.UIAxes,t,y, 'm' );     % Hussein: Different plot names were necessary in order to differentiate between the 4 graphed curves. This curve is colored magenta
            delete(app.line4);
            app.line4 = plot(app.UIAxes,t2,y2, 'c--');  % Hussein: This curve is colored cyan and is dashed
        end

        % Menu selected function: Data3Menu
        function Data3MenuSelected(app, event)
            load PendulumData3.mat;              % Hussein: Loads the saved raw data from a previously measured graph
            load PendulumData3F.mat;             % Hussein: Loads the saved filtered data from a previously measured graph
            hold (app.UIAxes, 'on')
            delete(app.line3);                   % Hussein: Same thing explained in line 160
            app.line3 = plot(app.UIAxes,t,y, 'm' );     % Hussein: Different plot names were necessary in order to differentiate between the 4 graphed curves. This curve is colored magenta
            delete(app.line4);
            app.line4 = plot(app.UIAxes,t2,y2, 'c--');  % Hussein: This curve is colored cyan and is dashed
        end

        % Value changed function: RecordData1Button
        function RecordData1ButtonValueChanged(app, event)
            app.buttonOn = app.RecordData1Button.Value; % Hussein: A function that is turned on or off. Used for determining when to save a measured graph

        end

        % Value changed function: RecordData2Button
        function RecordData2ButtonValueChanged(app, event)
            app.buttonOn2 = app.RecordData2Button.Value; % Hussein: A function that is turned on or off. Used for determining when to save a measured graph
            
        end

        % Value changed function: RecordData3Button
        function RecordData3ButtonValueChanged(app, event)
             app.buttonOn3 = app.RecordData3Button.Value; % Hussein: A function that is turned on or off. Used for determining when to save a measured graph
            
        end

        % Button pushed function: QuickInitButton
        function QuickInitButtonPushed(app, event)
            try
                % Developer function to make the project faster to test.
                % Won't be used in final product

                app.Uno = arduino('COM5', 'Uno', 'Libraries', 'Ultrasonic'); % assigning the arduino to the 'Uno' array for easy access

            catch
                msgbox('Arduino failed');
            end

            try
                app.ultraS = app.Uno.ultrasonic("D4", "D3");       % assigning the ultrasonic sensor to 'ultraS'

                msgbox ('Ardiono and Ultrasound ready');
            catch
                msgbox('Arduino Ready BUT Ultrasound failed');
                return;

            end

                            writeDigitalPin(app.Uno, 'D7', 1);
                writeDigitalPin(app.Uno, 'D6', 1);


        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            %value = app.DropDown.Value;
            
            i = 1;
            peak = 0;
            while (i <= size(app.y2, 2))
                y1 = y2(i);
                x1 = t2(i);

                y2 = y2(i);
                x2 = t2(i);

                yDelta = y2 - y1;
                xDelta = x2 - x1;

                slope = xDelta * yDelta;

                if (slope > 0) % Positive slope
                    sign = 1
                end

                if (slope < 0) % Negative slope
                    sign = 2
                end
                
                if sign == 1
                    peak = peak + 1;
                end
                
                if sign == 2
                    peak = peak + 1;
                end
                
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ECE3040Project1UIFigure = uifigure('Visible', 'off');
            app.ECE3040Project1UIFigure.Name = 'ECE 3040 Project 1';
            app.LoadMenu = uimenu(app.ECE3040Project1UIFigure);
            app.LoadMenu.Text = 'Load';
            app.Data1Menu = uimenu(app.LoadMenu);
            app.Data1Menu.MenuSelectedFcn = createCallbackFcn(app, @Data1MenuSelected, true);
            app.Data1Menu.Text = 'Data 1';
            app.Data2Menu = uimenu(app.LoadMenu);
            app.Data2Menu.MenuSelectedFcn = createCallbackFcn(app, @Data2MenuSelected, true);
            app.Data2Menu.Text = 'Data 2';
            app.Data3Menu = uimenu(app.LoadMenu);
            app.Data3Menu.MenuSelectedFcn = createCallbackFcn(app, @Data3MenuSelected, true);
            app.Data3Menu.Text = 'Data 3';
            app.UIAxes = uiaxes(app.ECE3040Project1UIFigure);
            title(app.UIAxes, 'Pendulum Distance')
            xlabel(app.UIAxes, 'Time (seconds)')
            ylabel(app.UIAxes, 'Distance (meters)')
            zlabel(app.UIAxes, 'Z')
            app.GoodDataLampLabel = uilabel(app.ECE3040Project1UIFigure);
            app.GoodDataLampLabel.Text = 'Good Data';
            app.GoodDataLamp = uilamp(app.ECE3040Project1UIFigure);
            app.ResetGraphButton = uibutton(app.ECE3040Project1UIFigure, 'push');
            app.ResetGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ResetGraphButtonPushed, true);
            app.ResetGraphButton.Text = 'Reset Graph';
            app.Measure7secondsButton = uibutton(app.ECE3040Project1UIFigure, 'push');
            app.Measure7secondsButton.ButtonPushedFcn = createCallbackFcn(app, @Measure7secondsButtonPushed, true);
            app.Measure7secondsButton.Text = 'Measure (7 seconds)';
            app.PortEditFieldLabel = uilabel(app.ECE3040Project1UIFigure);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.ECE3040Project1UIFigure, 'text');
            app.TriggerPinEditFieldLabel = uilabel(app.ECE3040Project1UIFigure);
            app.TriggerPinEditFieldLabel.Text = 'Trigger Pin';
            app.TriggerPinEditField = uieditfield(app.ECE3040Project1UIFigure, 'text');
            app.EchoPinEditFieldLabel = uilabel(app.ECE3040Project1UIFigure);
            app.EchoPinEditFieldLabel.Text = 'Echo Pin';
            app.EchoPinEditField = uieditfield(app.ECE3040Project1UIFigure, 'text');
            app.BadDataLampLabel = uilabel(app.ECE3040Project1UIFigure);
            app.BadDataLampLabel.Text = 'Bad Data';
            app.BadDataLamp = uilamp(app.ECE3040Project1UIFigure);
            app.InitializeArduinoButton = uibutton(app.ECE3040Project1UIFigure, 'push');
            app.InitializeArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @StartUno, true);
            app.InitializeArduinoButton.Text = {'Initialize Arduino'; ''};
            app.RecordData1Button = uibutton(app.ECE3040Project1UIFigure, 'state');
            app.RecordData1Button.ValueChangedFcn = createCallbackFcn(app, @RecordData1ButtonValueChanged, true);
            app.RecordData1Button.Text = 'Record Data 1';
            app.QuickInitButton = uibutton(app.ECE3040Project1UIFigure, 'push');
            app.QuickInitButton.ButtonPushedFcn = createCallbackFcn(app, @QuickInitButtonPushed, true);
            app.QuickInitButton.Visible = 'off';
            app.QuickInitButton.Text = 'Quick Init';
            app.MeasuredRawDataLabel = uilabel(app.ECE3040Project1UIFigure);
            app.MeasuredRawDataLabel.Text = 'Measured Raw Data = ';
            app.MeasuredFilteredDataLabel = uilabel(app.ECE3040Project1UIFigure);
            app.MeasuredFilteredDataLabel.Text = 'Measured Filtered Data = ';
            app.LoadedRawDataLabel = uilabel(app.ECE3040Project1UIFigure);
            app.LoadedRawDataLabel.Text = 'Loaded Raw Data = ';
            app.LoadedFilteredDataLabel = uilabel(app.ECE3040Project1UIFigure);
            app.LoadedFilteredDataLabel.Text = 'Loaded Filtered Data = ';
            app.Label = uilabel(app.ECE3040Project1UIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.ECE3040Project1UIFigure);
            app.Lamp_2Label = uilabel(app.ECE3040Project1UIFigure);
            app.Lamp_2Label.Text = '';
            app.Lamp_2 = uilamp(app.ECE3040Project1UIFigure);
            app.Lamp_3Label = uilabel(app.ECE3040Project1UIFigure);
            app.Lamp_3Label.Text = '';
            app.Lamp_3 = uilamp(app.ECE3040Project1UIFigure);
            app.Lamp_4Label = uilabel(app.ECE3040Project1UIFigure);
            app.Lamp_4Label.Text = '';
            app.Lamp_4 = uilamp(app.ECE3040Project1UIFigure);
            app.RecordData3Button = uibutton(app.ECE3040Project1UIFigure, 'state');
            app.RecordData3Button.ValueChangedFcn = createCallbackFcn(app, @RecordData3ButtonValueChanged, true);
            app.RecordData3Button.Text = 'Record Data 3';
            app.RecordData2Button = uibutton(app.ECE3040Project1UIFigure, 'state');
            app.RecordData2Button.ValueChangedFcn = createCallbackFcn(app, @RecordData2ButtonValueChanged, true);
            app.RecordData2Button.Text = 'Record Data 2';
            app.DropDownLabel = uilabel(app.ECE3040Project1UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.ECE3040Project1UIFigure);
            app.DropDown.Items = {'Get Period (Matrix Inverse)', 'Get Period (Gauss Siedel)', 'Get Period (LU Decomposition)'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Get Period (Matrix Inverse)';
            app.EditFieldLabel = uilabel(app.ECE3040Project1UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.ECE3040Project1UIFigure, 'numeric');
            app.EditField2Label = uilabel(app.ECE3040Project1UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.ECE3040Project1UIFigure, 'numeric');
            app.EditField3Label = uilabel(app.ECE3040Project1UIFigure);
            app.EditField3Label.Text = 'Edit Field3';
            app.EditField3 = uieditfield(app.ECE3040Project1UIFigure, 'numeric');
            % Show the figure after all components are created
            app.ECE3040Project1UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project_2_getting_period

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ECE3040Project1UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ECE3040Project1UIFigure)
        end
    end
end