classdef RobotControlModel_11 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RobotControlModelGUI            matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout7                     matlab.ui.container.GridLayout
        ConfigurationShownTableLabel    matlab.ui.control.Label
        DHParametersTable               matlab.ui.control.Table
        EndEffectorPanel                matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        PhysicalRobotControlClassRobotOnlyPanel  matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        TouchUntouchFlourButton         matlab.ui.control.StateButton
        SetCurrentZAsFlourButton        matlab.ui.control.StateButton
        SyncModeltoRobotButton          matlab.ui.control.Button
        SyncRobottoModelButton          matlab.ui.control.Button
        SynchronizeSwitch               matlab.ui.control.Switch
        SynchronizeLabel                matlab.ui.control.Label
        ArduinoConnectionLamp           matlab.ui.control.Lamp
        ConnectArduinoButton            matlab.ui.control.Button
        PositionWithRespecttoBoardPanel  matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        mmLabel_6                       matlab.ui.control.Label
        mmLabel_5                       matlab.ui.control.Label
        mmLabel_4                       matlab.ui.control.Label
        EEzLabel                        matlab.ui.control.Label
        EEyLabel                        matlab.ui.control.Label
        EExLabel                        matlab.ui.control.Label
        ZLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        ControlPanel                    matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        mmLabel_3                       matlab.ui.control.Label
        StepEditField                   matlab.ui.control.EditField
        StepEditFieldLabel              matlab.ui.control.Label
        RiseButton                      matlab.ui.control.Button
        LowerButton                     matlab.ui.control.Button
        BackwardButton                  matlab.ui.control.Button
        RightButton                     matlab.ui.control.Button
        LeftButton                      matlab.ui.control.Button
        ForwardButton                   matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        JointDhParametersTab            matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        JointOffsetdegreesPanel         matlab.ui.container.Panel
        offsetEditField                 matlab.ui.control.NumericEditField
        alphaEditField_2Label_2         matlab.ui.control.Label
        AssignParametersButton          matlab.ui.control.StateButton
        LinkTwistdegreesPanel           matlab.ui.container.Panel
        alphaEditField                  matlab.ui.control.NumericEditField
        alphaEditField_2Label           matlab.ui.control.Label
        LinkLengthmmPanel               matlab.ui.container.Panel
        aEditField                      matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        LinkOffsetmmPanel               matlab.ui.container.Panel
        d_maxEditField                  matlab.ui.control.NumericEditField
        d_maxEditFieldLabel             matlab.ui.control.Label
        d_minEditField                  matlab.ui.control.NumericEditField
        d_minEditFieldLabel             matlab.ui.control.Label
        dEditFieldLabel                 matlab.ui.control.Label
        dEditField                      matlab.ui.control.NumericEditField
        JointAngledegreesPanel          matlab.ui.container.Panel
        theta_maxEditField              matlab.ui.control.NumericEditField
        theta_maxEditFieldLabel         matlab.ui.control.Label
        theta_minEditField              matlab.ui.control.NumericEditField
        theta_minEditFieldLabel         matlab.ui.control.Label
        thetaEditField                  matlab.ui.control.NumericEditField
        thetaEditFieldLabel             matlab.ui.control.Label
        JointControlTab                 matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        MoveToPoseButton                matlab.ui.control.Button
        SelectPoseDropDown              matlab.ui.control.DropDown
        SelectPoseDropDownLabel         matlab.ui.control.Label
        Joint1ControlSlider             matlab.ui.control.Slider
        Joint1SliderLabel               matlab.ui.control.Label
        Joint5ControlSlider             matlab.ui.control.Slider
        Joint5SliderLabel               matlab.ui.control.Label
        Joint4ControlSlider             matlab.ui.control.Slider
        Joint4SliderLabel               matlab.ui.control.Label
        Joint3ControlSlider             matlab.ui.control.Slider
        Joint3SliderLabel               matlab.ui.control.Label
        Joint2ControlSlider             matlab.ui.control.Slider
        Joint2SliderLabel               matlab.ui.control.Label
        Joint5UnitLabel                 matlab.ui.control.Label
        Joint5ControlLabel              matlab.ui.control.Label
        Joint4UnitLabel                 matlab.ui.control.Label
        Joint4ControlLabel              matlab.ui.control.Label
        Joint3UnitLabel                 matlab.ui.control.Label
        Joint3ControlLabel              matlab.ui.control.Label
        Joint2UnitLabel                 matlab.ui.control.Label
        Joint2ControlLabel              matlab.ui.control.Label
        Joint1ControlLabel              matlab.ui.control.Label
        Joint1UnitLabel                 matlab.ui.control.Label
        GameClassRobotOnlyTab           matlab.ui.container.Tab
        GridLayout21                    matlab.ui.container.GridLayout
        PlayTurnLabel                   matlab.ui.control.Label
        MovetotheCellLabel              matlab.ui.control.Label
        OButton                         matlab.ui.control.Button
        XButton_10                      matlab.ui.control.Button
        XButton_8                       matlab.ui.control.Button
        XButton_7                       matlab.ui.control.Button
        XButton_6                       matlab.ui.control.Button
        XButton_5                       matlab.ui.control.Button
        XButton_4                       matlab.ui.control.Button
        XButton_3                       matlab.ui.control.Button
        XButton_9                       matlab.ui.control.Button
        XButton_2                       matlab.ui.control.Button
        XButton                         matlab.ui.control.Button
        PlayTicTacToeButton             matlab.ui.control.Button
        MovementsClassRobotOnlyTab      matlab.ui.container.Tab
        GridLayout18                    matlab.ui.container.GridLayout
        DrawWorkspaceEnvelopeButton     matlab.ui.control.Button
        DrawGridWithCirclesfixedButton  matlab.ui.control.Button
        DrawGridWithCrossesfixedButton  matlab.ui.control.Button
        DrawGridButton                  matlab.ui.control.Button
        DrawCrossButton                 matlab.ui.control.Button
        DrawCircleButton                matlab.ui.control.Button
        GridLayout5                     matlab.ui.container.GridLayout
        GridLayout20                    matlab.ui.container.GridLayout
        Gauge                           matlab.ui.control.LinearGauge
        SelectJointListBoxLabel         matlab.ui.control.Label
        GridLayout13                    matlab.ui.container.GridLayout
        OptimizationStatusLabel         matlab.ui.control.Label
        OptimizationStatusLamp          matlab.ui.control.Lamp
        OptimizeButton                  matlab.ui.control.Button
        GridLayout11                    matlab.ui.container.GridLayout
        mmLabel_2                       matlab.ui.control.Label
        BaseYEditField                  matlab.ui.control.NumericEditField
        BaseYEditFieldLabel             matlab.ui.control.Label
        GridLayout10                    matlab.ui.container.GridLayout
        mmLabel                         matlab.ui.control.Label
        BaseXEditField                  matlab.ui.control.NumericEditField
        BaseXEditFieldLabel             matlab.ui.control.Label
        CreateModelButton               matlab.ui.control.Button
        SelectJointListBox              matlab.ui.control.ListBox
        AutoconfigureButton             matlab.ui.control.Button
        RemoveAllJointsButton           matlab.ui.control.Button
        AddPrismaticJointButton         matlab.ui.control.Button
        AddRevoluteJointButton          matlab.ui.control.Button
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu_2                          matlab.ui.container.Menu
        Menu2_2                         matlab.ui.container.Menu
    end


    properties (Access = private)
        % Initialize robot Serial Link object
        robotSL =[];

        % Initialize DH parameters for each link. Row index represents joint index.
        % First column represents joint angle (rad),
        % Second column represents link offset (m),
        % Third column represents link length (m),
        % Fourth column represents link twist (rad),
        % Fifth column represents joint type (1-prismatic, 0-revolute)
        DHparametersAndType = [];

        % Initialize matrix that represents limits of matched index joint.
        % First column represents min value, while second column represents
        % max value. For prismatic joints, range of d parameter is
        % represented. For revolute, range of theta parameter is
        % represented.
        jointsLimits = [];

        % Global variable to hold all joints' offsets
        jointsOffsets = [];                     

        clipboardDimensions = [303, 231]/1000 ;% [width, height] in meters
        robotBasePosition = [0,0];             % [X, Y] in meters 
        robotAbsoluteEEPosition = [];           % Global variable that keeps track of the End-Effector position
        lastRobotAbsoluteEEPosition = [];

        % Matrix where each row contain set robot variables and distance
        % of end-effector
        measuredPointsMatrix = [];

        % Matrix that marks what DH parameters and offsets the user wants to optimize 
        optimizationMask = [];

        % Boolean variable that allows the user to choose if they want to
        % optimize the Base Position of the robot or not.
        optimizeBasePosition = false;

        % Boolean variable that allows the user to choose if they want to
        % plot the robot at every iteration of objective function call.
        plotRobotProgress = false;

        % Starting position of the robot
        startingVariables = [];

        % Array to hold all iterations of the total error
        totalErrorHistory = [];


        %%%%%%%%%%%% Hardware Variables
        device = [];
        servo1 = [];
        servo2 = [];
        servo3 = [];

        %hardcoded motor clibration. Row corisponds to motor, and columns
        %resprisent slope and intersept respectivelly. DH parameter as a
        %function of motor value (0-1)
        motorCalibration = [-135.78, 125.75; -142.43, 156.55; -27.5, 108.7];

        robotsSynct = true;

        flourD3 = 93.1608/1000;


    end

    methods (Access = private)

        % A function that, when called on, will refresh and update the DHtable with the robots current configuration
        function updateDHtable(app) 
            app.DHParametersTable.Data = [[1:size(app.DHparametersAndType, 1)]',app.DHparametersAndType];
        end


        % A function that allows the user to select a joint from the joints list to edit its parameters
        function updateSelectJointList(app) 
            counter = 1;
            for t = app.DHparametersAndType(:, 5)'
                if(t == 1)
                    jointName = strcat('P',num2str(counter));
                else
                    jointName = strcat('R',num2str(counter));
                end
                app.SelectJointListBox.Items(counter) = {jointName};
                counter = counter + 1;
            end
        end

        function configureJointControlTab(app)
            % Grouping joint control elements in the arrays for easy access
            jointControlSliders = [app.Joint1ControlSlider, app.Joint2ControlSlider,app.Joint3ControlSlider, app.Joint4ControlSlider,app.Joint5ControlSlider];
            jointControlLabels = [app.Joint1ControlLabel, app.Joint2ControlLabel,app.Joint3ControlLabel, app.Joint4ControlLabel,app.Joint5ControlLabel];
            jointUnitLabels = [app.Joint1UnitLabel, app.Joint2UnitLabel,app.Joint3UnitLabel, app.Joint4UnitLabel,app.Joint5UnitLabel];


            % Go though each joint index and assign the proper labels and units to the
            % DH parameter being manipulated for each joint
            for j = 1:size(app.DHparametersAndType, 1)
                jointType = app.DHparametersAndType(j, 5);
                if(jointType == 0)
                    jointControlSliders(j).Limits = app.jointsLimits(j, :) * 180/pi;
                    jointControlSliders(j).Value = app.DHparametersAndType(j, 1) * 180/pi;
                    jointUnitLabels(j).Text = 'deg';
                else
                    jointControlSliders(j).Limits = app.jointsLimits(j, :) * 1000;
                    jointControlSliders(j).Value = app.DHparametersAndType(j, 2) * 1000;
                    jointUnitLabels(j).Text = 'mm';
                end

                jointControlLabels(j).Text = num2str(jointControlSliders(j).Value);
                jointControlSliders(j).Enable = true;

            end

            app.MoveToPoseButton.Enable = true;
        end
        
        % A function that gets the variable DH parameters in array form
        function results = getArrayOfJointsVariablesFromDHparametersMatrix(app) 

            % Assuming all joints are revolute, assign in row form
            results = app.DHparametersAndType(:, 1)';

            % Modify starting configuration for prismatic joints.
            % For each joint index, if jointType is prismatic (i.e, 1), fix
            % starting configuration at the joint index
            for j = 1:size(app.DHparametersAndType, 1)
                if(app.DHparametersAndType(j, 5) == 1)
                    results(j) = app.DHparametersAndType(j, 2);
                end
            end
            
        end
        
        % A function that refreshes labels to display the current position of the end-effector
        function updateEEPositionLabels(app)    
            app.EExLabel.Text = num2str(app.robotAbsoluteEEPosition(1)*1000);
            app.EEyLabel.Text = num2str(app.robotAbsoluteEEPosition(2)*1000);
            app.EEzLabel.Text = num2str(app.robotAbsoluteEEPosition(3)*1000);
        end
        
        % The function that performs inverse kinematics to move the End-Effector to its next location based on user input
        function moveEE(app, unitVector, moveStep, updateGUI)          

            % Getting the current position of the robot
            currentRobotVariables = app.robotSL.getpos();

            % Forming the end effector's transformation matrix by using forward kinematics with 
            % the joint angles of the current robot
            EETransformationMatrix = app.robotSL.fkine(currentRobotVariables);  

            % Altering the end effector's translation vector by however much the user 
            % wants to move the end effector position and in the preferred direction
            EETransformationMatrix.t = EETransformationMatrix.t+unitVector'*moveStep;  

            % Using Inverse kinematics to determine the new joint angles
            % utilizing the altered EE transformation matrix and taking the initial guess 
            % to be the current robot angles. The initial guess is crucial to avoid useless multiple solutions
            newRobotVariables = app.robotSL.ikine(EETransformationMatrix, 'mask', [1 1 1 0 0 0], 'q0', currentRobotVariables);  

            % An if statement that checks if the new joint angles found are within the limits of the physical motors.
            % If not, the new joint angles are ignored and not plotted 
            
            if(~isempty(newRobotVariables)) 
                                            
                % Check if all joints are within limits
                jointsAtLimitCheck = app.robotSL.islimit(newRobotVariables);
                jointsWithinLimits = all(jointsAtLimitCheck(:) == 0);
                
    
                if(jointsWithinLimits)
                    % Update robot plot
                    app.robotSL.animate(newRobotVariables);
                    
                    % Update robot position vectors to the global EE
                    % transformation matrix
                    app.lastRobotAbsoluteEEPosition = app.robotAbsoluteEEPosition;
                    app.robotAbsoluteEEPosition = [EETransformationMatrix.t;1];
        
                    % Update GUI elements
                    if(updateGUI)
                        app.updateDHParametersMatrixFromVariables(newRobotVariables);
                        app.updateDHtable();
                        app.updateEEPositionLabels(); 
                        app.updateJointControlSliders();
                    end

                    app.tryAnimatePhysicalRobot(app.robotSL.getpos()); app.tryDrawingOnThePlot(); %%%NEW app.tryAnimatePhysicalRobot(app.robotSL.getpos()); %%%NEW

                else
                    %display message in console idicating what variables
                    %are not within limit
                    disp("Robot not within limits");
                    disp(jointsAtLimitCheck);
                    disp(currentRobotVariables);
                    disp(newRobotVariables);
                end
            end
            
        end
        
        % Function is self-explanatory. A function that takes in DH parameter variables as an input 
        % and updates a global matrix that constantly holds ALL the current DH parameters  
        function updateDHParametersMatrixFromVariables(app, variables)  

            % For loop to go through all input joint variables and place
            % them in their respective elements in the global matrix
            for j = 1:length(variables)
                jointType = app.DHparametersAndType(j, 5);
                
                if(jointType == 0)
                    app.DHparametersAndType(j, 1) = variables(j);
                else
                    app.DHparametersAndType(j, 2) = variables(j);
                end
            end
            
        end
        
        function updateJointControlSliders(app)
            % Grouping joint control elements in the arrays for easy
            % access. This has to be repeated because the variable
            % 'jointControlSliders' and others like it are local variables
            jointControlSliders = [app.Joint1ControlSlider, app.Joint2ControlSlider,app.Joint3ControlSlider, app.Joint4ControlSlider,app.Joint5ControlSlider];
            jointControlLabels = [app.Joint1ControlLabel, app.Joint2ControlLabel,app.Joint3ControlLabel, app.Joint4ControlLabel,app.Joint5ControlLabel];
            
            % Getting the variable DH parameters
            robotVariables = app.robotSL.getpos();

            % A for loop that updates the sliders that control the variable
            % DH parameters when manually entering a value through the
            % 'Joint Dh Parameters' Menu
            for j = 1:length(robotVariables)
                jointType = app.DHparametersAndType(j, 5);
                
                if(jointType == 0)
                    new_value = robotVariables(j)*180/pi;
                    jointControlSliders(j).Value = new_value;
                    jointControlLabels(j).Text = num2str(new_value);
                else
                    new_value = robotVariables(j)*1000;
                    jointControlSliders(j).Value = new_value;
                    jointControlLabels(j).Text = num2str(new_value);
                end
            end
        end
        
        % A function to read data files for parameters and form a
        % matrix. There must be 10 parameters for this to function properly
        function outputMatrix = fileToMatrix(app, title)
            
            newMatrix = [];
            % Prompt use the file location then open it
            [file,~] = uigetfile({'*.*'}, title);
            fileIdentifier = fopen(file, 'r');

            % Read first line, ignoring the results as it is the header, and 
            % continue to read taking every 10 elements before making new line 
            readLine = fgets(fileIdentifier);
            lineBeingRead = 1;
            while(readLine ~= -1)
                if(lineBeingRead > 1)
                    numbersInLine = sscanf(readLine, '%f %f %f %f %f %f %f %f %f %f')';
                    if(length(numbersInLine)<10)
                        elementsToAdd = 10 - length(numbersInLine);
                        for i = 1:elementsToAdd
                            numbersInLine(end+1) = 0;
                        end
                    end
                    
                    % Forming the matrix with 10 elements in each row
                    newMatrix = [newMatrix; numbersInLine];
                end

                readLine = fgets(fileIdentifier);
                lineBeingRead = lineBeingRead + 1;
            end

            % Close file
            fclose(fileIdentifier);

            outputMatrix = newMatrix;
        end


        % Our objective function to find the error between the simulated
        % points and the actual measured points by creating a robot using
        % the DHparametersAndType matrix and masked parameters.
        function error = objectiveFunction(app, a)

            % Variable to keep track of total error
            totalError = 0;
            % Counter variable
            aCounter = 1;

            % Nested for loop to create robot links
            for j = 1:size(app.DHparametersAndType, 1)
               % Link initializer [theta, d, a, alpha]. Resetted every
               % iteration to hold new link/joint parameters
               initializer = [];

               % Go though mask and identify if variable has to be optimized
               % and used from 'a' or used from existing DH parameters
               for par = 1:4

                   % Don't optimize the parameter, use existing DH parameter
                  if(app.optimizationMask(j, par) == 0) 
                      initializer(end+1) = app.DHparametersAndType(j, par);
                  else 
                      % Optimize variable and use 'a' 
                      initializer(end+1) = a(aCounter);
                      aCounter = aCounter + 1;
                  end
               end

               % Create link
               L(j) = Link(initializer);

              % Change the type of the link to prismatic if needed
              if(app.DHparametersAndType(j, 5) == 1)
                    L(j).jointtype = 'P';
              end


              % Determine if offset is to be optimized
              if(app.optimizationMask(j, end) == 1) % Optimize the offset
                  L(j).offset = a(aCounter);
                  aCounter = aCounter + 1;
              else % Don't optimize and use existing offset
                  L(j).offset = app.jointsOffsets(j);
              end


            end


            % Create robot with L links
            testRobot = SerialLink(L, 'name', 'tester');

            % Optimizing robot base if selected
            if(app.optimizeBasePosition)
                testRobot.base = transl(a(aCounter), a(aCounter + 1), 0);
            else
                testRobot.base = transl(app.robotBasePosition(1), app.robotBasePosition(2), 0);
            end


            % For loop to compare the measured points to the simulated
            % points and calculating the error between the two.
            for p = 1:size(app.measuredPointsMatrix, 1)
                
                % Getting our measured robot variables
                robotVariables = app.measuredPointsMatrix(p, 1:end-2);

                % Getting simulated robot position using measured robot
                % variables
                EEposition = testRobot.fkine(robotVariables);

                simulatedXPos = EEposition.t(1);
                simulatedYPos = EEposition.t(2);
                simulatedZPos = EEposition.t(3);

                % Calculating error by taking the absolute distance between
                % where the end effector should be vs. where it actually
                % was physically
                simulatedEEdistanceXY = sqrt(simulatedXPos*simulatedXPos+simulatedYPos*simulatedYPos);
                measuredEEdistanceXY = app.measuredPointsMatrix(p, end-1);
                measuredEEdistanceZ = app.measuredPointsMatrix(p, end);

                EEdistanceDifferenceZ = simulatedZPos - measuredEEdistanceZ;
                EEdistanceDifferenceXY = simulatedEEdistanceXY - measuredEEdistanceXY;

                % Computing total error between simulated and measured
                % points
                partialError = sqrt(EEdistanceDifferenceXY * EEdistanceDifferenceXY + EEdistanceDifferenceZ*EEdistanceDifferenceZ);
                totalError = totalError + partialError;

            end

            % Adding iteration error to global error history to plot
            % optimization progress
            app.totalErrorHistory(end+1) = totalError;
            figure(2);
            plot(app.totalErrorHistory);
            xlabel('Iteration'); ylabel('Total Error');
            title('Optimization Progress');

            % plot the robot iteration if user set it previously
            if(app.plotRobotProgress)
                figure(3);
                testRobot.plot(app.startingVariables, 'workspace', ...
                        [0, app.clipboardDimensions(1), ...
                        0, app.clipboardDimensions(2), ...
                        0, .133 ], 'floorlevel',0, 'scale', .4);

            end

            % Returning total error to fminsearch for optimization
            error = totalError;
            
            
        end
        
        function ServoValues = robotVariablesToServoValues(app, robotVariables)
            servo1Value = (robotVariables(1)*180/pi-app.motorCalibration(1,2))/app.motorCalibration(1,1);
            servo2Value = (robotVariables(2)*180/pi-app.motorCalibration(2,2))/app.motorCalibration(2,1);
            servo3Value = (robotVariables(3)*1000-app.motorCalibration(3,2))/app.motorCalibration(3,1);

            ServoValues = [servo1Value, servo2Value, servo3Value];
        end

        function RobotVariables = servoValuesToRobotVariables(app, servoValues)
            theta1 = app.motorCalibration(1, 1) * servoValues(1) + app.motorCalibration(1, 2);
            theta2 = app.motorCalibration(2, 1) * servoValues(2) + app.motorCalibration(2, 2);
            d3 = app.motorCalibration(3, 1) * servoValues(3) + app.motorCalibration(3, 2);

            RobotVariables = [theta1/180*pi, theta2/180*pi, d3/1000];
        end
        
        function tryAnimatePhysicalRobot(app, robotVariables)
            if(~isempty(app.device) && app.robotsSynct)
                servoValues = app.robotVariablesToServoValues(robotVariables);
                app.servo1.writePosition(servoValues(1));
                app.servo2.writePosition(servoValues(2));
                app.servo3.writePosition(servoValues(3));
            end

        end
        
        function result = isTouchingTheBoard(app, Zposition)
            d3Value = app.DHparametersAndType(1, 2)+app.DHparametersAndType(2, 2)-Zposition;
            zChange = app.flourD3 - d3Value;
            result = abs(zChange) <= .1/1000;
        end

        function lowerOrRiseToWrite(app)
            currentPos = app.robotSL.getpos();
            zChange = app.flourD3 - currentPos(3);
            if(abs(zChange) <= .1/1000) %marker is lowered, so rise pen by 10 mm
                app.moveEE([0,0,1], 10/1000, true);
            else
                app.moveEE([0,0,-1], zChange, true);
            end
            
        end

        
        function drawCircle(app, centerXY, radius, steps)
            app.Gauge.Value = 0;  

            %prepare robot by moving ee to the circle center
            currentXY = app.robotAbsoluteEEPosition(1:2)';
            XYchange = centerXY - currentXY;
            app.moveEE([XYchange, 0], 1, false);
            
            %move to the side of the circle
            app.moveEE([1, 0, 0], radius, false);
            app.lowerOrRiseToWrite();
            
            
            previousXYEEPosition = [centerXY(1)+radius, centerXY(2)];
            for a = linspace(0, 360, steps)
              
                newXYEEPosition = [centerXY(1) + cosd(a)*radius, centerXY(2) + sind(a)*radius];
                dXYEEPosition = newXYEEPosition-previousXYEEPosition;

                app.moveEE([dXYEEPosition, 0], 1, false);

                previousXYEEPosition = newXYEEPosition;
                app.Gauge.Value = app.Gauge.Value + 100/steps;

            end
            app.lowerOrRiseToWrite();
            app.moveEE([-1, 0, 0], radius, true);
        end


        function drawLine(app, startXY, endXY, steps)
            app.Gauge.Value = 0;  


             %prepare robot by moving ee to the starting location
            currentXY = app.robotAbsoluteEEPosition(1:2)';
            XYchange = startXY - currentXY;
            app.moveEE([XYchange, 0], 1, false);
            
            lengthsOfLineXY = endXY-startXY;
            smallChangeInX = lengthsOfLineXY(1)/steps;
            smallChangeInY = lengthsOfLineXY(2)/steps;

            app.lowerOrRiseToWrite();
            for s = 1:steps
                   app.moveEE([smallChangeInX, smallChangeInY, 0], 1, false)
                   app.Gauge.Value = app.Gauge.Value + 100*s/steps;

            end
            app.lowerOrRiseToWrite();
        end
        
        %enter parameters in meters
        function drawGrid(app, x, y, width, height)
        
            app.drawLine([x+width/3, y], [x+width/3, y+height], height*1000/2)
            app.drawLine([x+width/3*2, y], [x+width/3*2, y+height], height*1000/2)
            
            app.drawLine([x, y+height/3], [x+width, y+height/3], width*1000/2);
            app.drawLine([x, y+height/3*2], [x+width, y+height/3*2], width*1000/2);
        end


        function tryDrawingOnThePlot(app)
            if(app.isTouchingTheBoard(app.lastRobotAbsoluteEEPosition(3)) && app.isTouchingTheBoard(app.robotAbsoluteEEPosition(3)))
                figure(1)
                for i = 1:2
                    subplot(2, 1, i)
                    plot([app.lastRobotAbsoluteEEPosition(1), app.robotAbsoluteEEPosition(1)], [app.lastRobotAbsoluteEEPosition(2), app.robotAbsoluteEEPosition(2)], 'r', 'LineWidth',2);
                    
                end
            end
            
        end
        
        function drawCross(app, centerXY, radius, steps)
            x = centerXY(1);
            y = centerXY(2);
            app.drawLine([x+radius*cosd(180+45), y+radius*sind(180+45)], [x+radius*cosd(45), y+radius*sind(45)], steps);
            app.drawLine([x+radius*cosd(180-45), y+radius*sind(180-45)], [x+radius*cosd(-45), y+radius*sind(-45)], steps);

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupHandler(app)
            % Convenience function that perfectly sizes the GUI to fit on
            % the left-hand side of your monitor
            screenSize = get(0,'ScreenSize');
            app.RobotControlModelGUI.Position = [0,50,screenSize(3)/2, screenSize(4)-75];
        end

        % Button pushed function: AddRevoluteJointButton
        function addRevoluteJoint(app, event)
            
            % Function that creates a revolute joint and adds it to the
            % current list of joints. 
            % Default parameters are set to [0 0 0 0 0], [0, 2*pi], and [0]
            numberOfexistingJoints = size(app.DHparametersAndType, 1);
            newJointName = strcat('R',num2str(numberOfexistingJoints+1));

            app.SelectJointListBox.Items(end + 1) = {newJointName};
            app.DHparametersAndType =[app.DHparametersAndType; 0,0,0,0,0];
            app.jointsLimits = [app.jointsLimits;
                                 [0, 2*pi]];
            app.jointsOffsets = [app.jointsOffsets;
                                 0];

            app.updateDHtable();
        end

        % Button pushed function: AddPrismaticJointButton
        function addPrismaticJoint(app, event)
            
            % Function that creates a prismatic joint and adds it to the
            % current list of joints. 
            % Default parameters are set to [0 0 0 0 1], [0, .01], and [0]
            numberOfexistingJoints = size(app.DHparametersAndType, 1);
            newJointName = strcat('P',num2str(numberOfexistingJoints+1));

            app.SelectJointListBox.Items(end + 1) = {newJointName};
            app.DHparametersAndType =[app.DHparametersAndType; 0,0,0,0,1];
            app.jointsLimits = [app.jointsLimits;
                                 [0, .01]];
            app.jointsOffsets = [app.jointsOffsets;
                                 0];

            app.updateDHtable();
        end

        % Button pushed function: RemoveAllJointsButton
        function removeAllJoints(app, event)
            % Grouping joint control elements in the arrays for easy access
            jointControlSliders = [app.Joint1ControlSlider, app.Joint2ControlSlider,app.Joint3ControlSlider, app.Joint4ControlSlider,app.Joint5ControlSlider];
            jointControlLabels = [app.Joint1ControlLabel, app.Joint2ControlLabel,app.Joint3ControlLabel, app.Joint4ControlLabel,app.Joint5ControlLabel];
            jointUnitLabels = [app.Joint1UnitLabel, app.Joint2UnitLabel,app.Joint3UnitLabel, app.Joint4UnitLabel,app.Joint5UnitLabel];

            % Emptying out global variables
            app.SelectJointListBox.Items = {};
            app.jointsLimits = [];
            app.jointsOffsets = [];
            app.DHparametersAndType = [];
            
            % Refreshing DH table with cleared out parameters
            app.updateDHtable();

            % Reseting Joint Control Panel to default
            for j = 1:5
                jointControlSliders(j).Enable = false;
                jointControlLabels(j).Text = "";
                jointUnitLabels(j).Text = "";
            end

            
        end

        % Value changed function: SelectJointListBox
        function jointSelected(app, event)
            % Finding which joint has been selected by the user
            selectedJointName = app.SelectJointListBox.Value;
            selectedJointIndex = str2num(selectedJointName(2));

            % Getting the parameters of the selected joint
            app.thetaEditField.Value = app.DHparametersAndType(selectedJointIndex, 1)*180/pi;
            app.dEditField.Value = app.DHparametersAndType(selectedJointIndex, 2)*1000;
            app.aEditField.Value = app.DHparametersAndType(selectedJointIndex, 3)*1000;
            app.alphaEditField.Value = app.DHparametersAndType(selectedJointIndex, 4)*180/pi;
            jointType = app.DHparametersAndType(selectedJointIndex, 5); %where 1 is prismatic and 0 is revolute
            app.offsetEditField.Value = app.jointsOffsets(selectedJointIndex)*180/pi;


            % Setting joint parameters to editable field boxes for the user
            % to see and manipulate.
            % Activating / Deactivating edit field boxes for min/max values for variables
            if(jointType == 0)                                                  
                app.d_minEditField.Editable = false;
                app.d_maxEditField.Editable = false;
                app.d_minEditField.Value = 0;
                app.d_maxEditField.Value = 0;

                app.theta_minEditField.Editable = true;
                app.theta_maxEditField.Editable = true;
                app.theta_minEditField.Value = app.jointsLimits(selectedJointIndex, 1)*180/pi;
                app.theta_maxEditField.Value = app.jointsLimits(selectedJointIndex, 2)*180/pi;
            else
                app.theta_minEditField.Editable = false;
                app.theta_maxEditField.Editable = false;
                app.theta_minEditField.Value = 0;
                app.theta_maxEditField.Value = 0;

                app.d_minEditField.Editable = true;
                app.d_maxEditField.Editable = true;
                app.d_minEditField.Value = app.jointsLimits(selectedJointIndex, 1)*1000;
                app.d_maxEditField.Value = app.jointsLimits(selectedJointIndex, 2)*1000;
            end


        end

        % Value changed function: AssignParametersButton
        function setDhParameters(app, event)
            
            % Getting the index of the selected joint
            selectedJointIndex = str2num(app.SelectJointListBox.Value(2));
            
            % Getting the new, user defined parameters of the joint
            new_theta = app.thetaEditField.Value*pi/180;
            new_d = app.dEditField.Value/1000;
            new_a = app.aEditField.Value/1000;
            new_alpha = app.alphaEditField.Value*pi/180;
            new_jointOffset = app.offsetEditField.Value*pi/180;

            % Reading the joint limits of the selected joint to see if they have
            % changed
            jointType = app.DHparametersAndType(selectedJointIndex, 5);
            if(jointType == 0)
                jointLimit = [app.theta_minEditField.Value, app.theta_maxEditField.Value]*pi/180;
            else
                jointLimit = [app.d_minEditField.Value, app.d_maxEditField.Value]/1000;
            end

            % Applying the new parameters of the selected joint to their
            % respective global variable of joint parameters
            app.jointsLimits(selectedJointIndex, :) = jointLimit;
            app.jointsOffsets(selectedJointIndex) = new_jointOffset;
            app.DHparametersAndType(selectedJointIndex, 1:end-1) = [new_theta, new_d, new_a, new_alpha];
            
            % Updating the table with the new parameters
            app.updateDHtable();

        end

        % Button pushed function: CreateModelButton
        function createRobot(app, event)
            % If statement that will only create robot if there is at least
            % one or more joints.
            if(size(app.DHparametersAndType, 1)>0)
                % Create robot links using DHparametersAndType,
                % jointsLimits, and jointsOffsets metrices
                for l = 1:size(app.DHparametersAndType, 1)
                    L(l) = Link(app.DHparametersAndType(l, :));
                    L(l).qlim = app.jointsLimits(l, :);
                    L(l).offset = app.jointsOffsets(l);
                end
                
                % Assigning the robot object
                app.robotSL = SerialLink(L, 'name', 'robot');

                % Moving the base of the robot to the specified robot base
                app.robotSL.base = transl(app.robotBasePosition(1), app.robotBasePosition(2), 0);

                % Getting initial robot variables from DHparametersAndType
                startingRobotVariables = app.getArrayOfJointsVariablesFromDHparametersMatrix();
                % Assigning variables to the global variable
                app.startingVariables = startingRobotVariables;


                % Configure the figure to plot the robot
                close all                                                   % Close all figures
                fugure1Handle=figure(1);                                    % Get figure handle to configure
                figurePosition = app.RobotControlModelGUI.Position;         % Start off with the same position as GUI
                figurePosition(1) = figurePosition(1)+figurePosition(3);    % Move figure to the right side of the screen for convenience
                figurePosition(2) = figurePosition(2)-50;                   % Adjust figure y position
                set(fugure1Handle,'Position',figurePosition)                % Apply settings to the figure


                % Plot robot workspace with the clipboard dimensions and
                % scale the elements to look good. Use starting variables as
                % created by the user. Plot robot 2 times for different views
                for i = 1:2
                    % Selecting subplot
                    subplot(2,1,i)

                    app.robotSL.plot(startingRobotVariables, 'workspace', ...
                        [0, app.clipboardDimensions(1), ...
                        0, app.clipboardDimensions(2), ...
                        0, .133 ], 'floorlevel',0, 'scale', .4);
                    
                    hold on 

                    if(i == 1)
                        view ([0 0 90])
                    end
                end
                
                % Updating global EE position
                app.lastRobotAbsoluteEEPosition = app.robotAbsoluteEEPosition;
                app.robotAbsoluteEEPosition = [app.robotSL.fkine(startingRobotVariables).t;1];

                % Configuring and updating sliders and labels for robot
                % manipulation
                app.configureJointControlTab();
                app.TabGroup.SelectedTab = app.JointControlTab;
                app.updateEEPositionLabels(); 
            end
        end

        % Button pushed function: AutoconfigureButton
        function autoconfigureRobot(app, event)

            % Prompt user to open robot's measurement file
            robotMeasurementMatrix = app.fileToMatrix("Select Measured DH Parameters File");

            % Assign DH parameters
            % First column represents joint angle,
            % Second column represents link offset,
            % Third column represents link length,
            % Fourth column represents link twist,
            % Fifth column represents joint type (1-prismatic, 0-revolute)
            app.DHparametersAndType = robotMeasurementMatrix(:, 1:5);

            % Convert to SI units
            % First column represents joint angle (rad),
            app.DHparametersAndType(:, 1) = app.DHparametersAndType(:, 1)*pi/180;
            % Second column represents link offset (m),
            app.DHparametersAndType(:, 2) = app.DHparametersAndType(:, 2)/1000;
            % Third column represents link length (m),
            app.DHparametersAndType(:, 3) = app.DHparametersAndType(:, 3)/1000;
            % Fourth column represents link twist (rad),
            app.DHparametersAndType(:, 4) = app.DHparametersAndType(:, 4)*pi/180;


            % Assign joints offsets parameters and convert to SI
            app.jointsOffsets = robotMeasurementMatrix(:, 6) * pi / 180;

            % Assign joints limits, convert to SI assuming all joints are
            % revolute
            app.jointsLimits = robotMeasurementMatrix(:, 7:8) * pi / 180;
            
            % Fix conversion on prismatic joints
            for j = 1:size(app.jointsLimits, 1)
                if(app.DHparametersAndType(j, 5) == 1) % is prismatic
                    app.jointsLimits(j, :) = app.jointsLimits(j, :)*180/pi/1000;
                end
            end

            % Assigning base position, convert to SI
            app.robotBasePosition = robotMeasurementMatrix(1, 9:10)/1000;
            app.BaseXEditField.Value = app.robotBasePosition(1)*1000;
            app.BaseYEditField.Value = app.robotBasePosition(2)*1000;

            % Updating DH table and joint list
            app.updateDHtable();
            app.updateSelectJointList();

        end

        % Value changed function: Joint1ControlSlider, 
        % ...and 4 other components
        function updateJointsConfiguration(app, event)
            
            % Group joint control elements in the arrays for easy access
            jointControlSliders = [app.Joint1ControlSlider, app.Joint2ControlSlider,app.Joint3ControlSlider, app.Joint4ControlSlider,app.Joint5ControlSlider];
            jointControlLabels = [app.Joint1ControlLabel, app.Joint2ControlLabel,app.Joint3ControlLabel, app.Joint4ControlLabel,app.Joint5ControlLabel];
            
            % Update DH parameters matrix from new slider positions and
            % go through joint indices.
            for j = 1:size(app.DHparametersAndType, 1)
                jointType = app.DHparametersAndType(j, 5);
                jointControlLabels(j).Text = num2str(jointControlSliders(j).Value);

                % Get new slider value and convert it to SI units
                if(jointType == 0)
                    app.DHparametersAndType(j, 1) = jointControlSliders(j).Value*pi/180;
                else
                    app.DHparametersAndType(j, 2) = jointControlSliders(j).Value/1000;
                end
            end

            % Updating DH table with new parameters
            app.updateDHtable();

            % Getting robot variables
            robotVariables= app.getArrayOfJointsVariablesFromDHparametersMatrix();
            
            % Plotting the robot with the new variables
            app.robotSL.animate(robotVariables);
           
            % Updating global variable of the EE position with the new,
            % plotted robot
            app.lastRobotAbsoluteEEPosition = app.robotAbsoluteEEPosition;
            app.robotAbsoluteEEPosition = [app.robotSL.fkine(robotVariables).t;1];
            
            % Updating labels indicating XYZ position of the EE
            app.updateEEPositionLabels(); app.tryAnimatePhysicalRobot(app.robotSL.getpos()); app.tryDrawingOnThePlot(); %%%NEW


        end

        % Value changed function: BaseXEditField
        function editBaseX(app, event)
            % Textbox to edit the X position of base.
            value = app.BaseXEditField.Value;
            app.robotBasePosition(1) = value/1000;
        end

        % Value changed function: BaseYEditField
        function editBaseY(app, event)
            % Textbox to edit the Y position of base.
            value = app.BaseYEditField.Value;
            app.robotBasePosition(2) = value/1000;
        end

        % Button pushed function: ForwardButton
        function MoveEEForward(app, event)
            % Getting how much the user wants to move the end effector in mm
            moveStep = str2num(app.StepEditField.Value)/1000; 

            % Button to move EE slightly forwards though robotSystem object 
            app.moveEE([0, 1, 0], moveStep, true);
        end

        % Button pushed function: BackwardButton
        function moveEEBackwards(app, event)
            % Getting how much the user wants to move the end effector in mm
            moveStep = str2num(app.StepEditField.Value)/1000; 

            % Button to move EE slightly backwards.
            app.moveEE([0, -1, 0], moveStep, true);
        end

        % Button pushed function: RightButton
        function moveEERight(app, event)
            % Getting how much the user wants to move the end effector in mm
            moveStep = str2num(app.StepEditField.Value)/1000; 

            % Button to move EE slightly right.
            app.moveEE([1, 0, 0], moveStep, true);
        end

        % Button pushed function: LeftButton
        function moveEELeft(app, event)
            % Getting how much the user wants to move the end effector in mm
            moveStep = str2num(app.StepEditField.Value)/1000; 

            % Button to move EE slightly left.
            app.moveEE([-1, 0, 0], moveStep, true);
        end

        % Button pushed function: LowerButton
        function moveEEDown(app, event)
            % Getting how much the user wants to move the end effector in mm
            moveStep = str2num(app.StepEditField.Value)/1000; 

            % Button to move EE slightly down.
            app.moveEE([0, 0, -1], moveStep, true);
        end

        % Button pushed function: RiseButton
        function moveEEUp(app, event)
            % Getting how much the user wants to move the end effector in mm
            moveStep = str2num(app.StepEditField.Value)/1000; 

            % Button to move EE slightly up.
            app.moveEE([0, 0, 1], moveStep, true);
        end

        % Button pushed function: OptimizeButton
        function optimizeDhParameters(app, event)

            % Change the color of the lamp and lamp label to let the user
            % know that optimization is in process
            app.OptimizationStatusLamp.Color = [1.0, 0, 0]; % rgb 0-1, (Red)
            app.OptimizationStatusLabel.Text = "In process";

            % Ask user if base needs to be optimized
            answer = questdlg("Optimize base position?");
            app.optimizeBasePosition = strcmp(answer, 'Yes');

            % Ask user if to draw robot every iteration of the objective
            % function.
            answer = questdlg("Plot robot in every iteration of objective function?");
            app.plotRobotProgress = strcmp(answer, 'Yes');

            
            % File is already in SI units
            % Using UDF to get measured points matrix
            app.measuredPointsMatrix = app.fileToMatrix("Select Optimization File");
            % Trimming excess columns
            app.measuredPointsMatrix = app.measuredPointsMatrix(:, 1:5);


            % Open optimization mask file and get its matrix
            app.optimizationMask = app.fileToMatrix("Select Optimization Mask");
            % Trimming Excess columns
            app.optimizationMask = app.optimizationMask(:, 1:5);


            % Create matrix with both starting DH parameters and offsets
            DhParametersAndOffsets = [app.DHparametersAndType(:, 1:4), app.jointsOffsets(:)];
            
            % Vector to house the initial guesses for optimization
            measuredParametersToOptimize = [];

            % Go though optimization mask and identify DH parameters to
            % optimize.

            % Nested for loop to go through each joint and its parameters
            for j = 1:size(DhParametersAndOffsets, 1)  
                for par = 1:5
                    if(app.optimizationMask(j, par) == 1)
                        measuredParametersToOptimize(end+1) = DhParametersAndOffsets(j, par);
                    end
                end
            end

            % Global variable check to see if user wants base to be
            % optimized
            if(app.optimizeBasePosition)
                measuredParametersToOptimize(end+1) = app.robotBasePosition(1); 
                measuredParametersToOptimize(end+1) = app.robotBasePosition(2); 
            end

            % Clearing global variable to keep track of error through each iteration for plotting purposes
            app.totalErrorHistory = [];

            % Using 'fminsearch' to find optimal DH parameters identified
            % in the mask matrix
            optimizedParameters = fminsearch(@(a) app.objectiveFunction(a), measuredParametersToOptimize);
            
            % Nested for loop to go though DhParametersAndOffsets matrix and use mask matrix to
            % replace measured parameters with the optimized ones.
            % 'aCounter' is a counter variable to indicate index of 'optimizedParameters' 
            aCounter = 1;
            for j = 1:size(DhParametersAndOffsets, 1)
                for par = 1:size(DhParametersAndOffsets, 2)
                    % Replacing the parameter via mask
                    if(app.optimizationMask(j, par) == 1) 
                        DhParametersAndOffsets(j, par) = optimizedParameters(aCounter);
                        aCounter = aCounter + 1;
                    end
                end
            end

            % If robot base was optimized, set robot base to new parameters
            if(app.optimizeBasePosition)
                app.robotBasePosition(1) = optimizedParameters(end-1);
                app.robotBasePosition(2) = optimizedParameters(end);

                app.BaseXEditField.Value = app.robotBasePosition(1)*1000;
                app.BaseYEditField.Value = app.robotBasePosition(2)*1000;

            end

            % Update DH parameters and type matrix
            app.DHparametersAndType(:, 1:4) = DhParametersAndOffsets(:, 1:4);
            app.jointsOffsets = DhParametersAndOffsets(:, 5);
            app.updateDHParametersMatrixFromVariables(app.startingVariables);

            % Updating DH table with new, optimzed parameters
            app.updateDHtable();

            % Change the color of the lamp and lamp label to let the user
            % know that optimization is complete.
            app.OptimizationStatusLamp.Color = [0, 1.0, 0]; % rgb 0-1 (Green)
            app.OptimizationStatusLabel.Text = "Done";


        end

        % Button pushed function: ConnectArduinoButton
        function connectArduino(app, event)
            if(isempty(app.device)) %arduino is not connected
                com = char (inputdlg ('What COM Port?' , 'COM Select', 1, {'COM11'}));
                try
                    app.device = arduino (com, 'uno', 'libraries', 'Servo');
                catch
                    msgbox ('Port failed', com, 'Error');
                    app.device = [];
                    return;
                end
                app.ArduinoConnectionLamp.Color = [0,1,0];
                app.ConnectArduinoButton.Enable = false;
    
                %connect servos
                app.servo1 = app.device.servo ('D9','MinPulseDuration',900e-6,'MaxPulseDuration',2100e-6);
                app.servo2 = app.device.servo ('D10','MinPulseDuration',900e-6,'MaxPulseDuration',2100e-6);
                app.servo3 = app.device.servo ('D11', 'MinPulseDuration',1500e-6,'MaxPulseDuration',1900e-6);
    
                %set to physical robot default position
                app.tryAnimatePhysicalRobot(app.robotSL.getpos()); app.tryDrawingOnThePlot();

                app.SyncModeltoRobotButton.Enable = true;
                app.SyncRobottoModelButton.Enable = true;

            end
        end

        % Button pushed function: MoveToPoseButton
        function setRobotToSelectedPose(app, event)
            selectedPoseName = app.SelectPoseDropDown.Value;
            newRobotVariables=[];

            if(strcmp(selectedPoseName, 'Cap Pose'))
                newRobotVariables = [app.jointsLimits(1, 2), app.jointsLimits(2, 1), app.jointsLimits(3, 1)];
            elseif(strcmp(selectedPoseName, 'Starting Pose'))
                newRobotVariables = app.startingVariables;
            end

            % Update robot plot
            app.robotSL.animate(newRobotVariables);
            
            % Update robot position vectors to the global EE
            % transformation matrix
            app.lastRobotAbsoluteEEPosition = app.robotAbsoluteEEPosition;
            app.robotAbsoluteEEPosition = [app.robotSL.fkine(newRobotVariables).t;1];

            % Update GUI elements
            app.updateDHParametersMatrixFromVariables(newRobotVariables);
            app.updateDHtable();
            app.updateEEPositionLabels(); app.tryAnimatePhysicalRobot(app.robotSL.getpos()); app.tryDrawingOnThePlot(); %%%NEW 
            
            app.updateJointControlSliders();
        end

        % Value changed function: SynchronizeSwitch
        function changeRobotSyncranation(app, event)
            value = app.SynchronizeSwitch.Value;
            if(strcmp(value, 'Yes'))
                app.robotsSynct = true;
            else
                app.robotsSynct = false;
            end
        end

        % Button pushed function: SyncRobottoModelButton
        function syncRobotToModel(app, event)
            app.robotsSynct = true;
            app.tryAnimatePhysicalRobot(app.robotSL.getpos()); app.tryDrawingOnThePlot();
            app.robotsSynct = false;
        end

        % Button pushed function: SyncModeltoRobotButton
        function syncModelToRobot(app, event)
            robotVariables = app.servoValuesToRobotVariables([app.servo1.readPosition(), app.servo2.readPosition(), app.servo3.readPosition()]);
            app.robotSL.animate(robotVariables);

            % Update robot position vectors to the global EE
            % transformation matrix
            app.lastRobotAbsoluteEEPosition = app.robotAbsoluteEEPosition;
            app.robotAbsoluteEEPosition = [app.robotSL.fkine(robotVariables).t;1];

            % Update GUI elements
            app.updateDHParametersMatrixFromVariables(robotVariables);
            app.updateDHtable();
            app.updateEEPositionLabels(); app.tryAnimatePhysicalRobot(app.robotSL.getpos()); app.tryDrawingOnThePlot(); %%%NEW app.tryAnimatePhysicalRobot(app.robotSL.getpos()); %%%NEW
            app.updateJointControlSliders();
        end

        % Button pushed function: DrawCircleButton
        function drawCircleButton(app, event)
            defaultPositionMM = {'54.424', '115.911', '5', '50'};
            userPositionMM = inputdlg({'x (mm): ', 'y (mm): ', 'radius (mm): ', 'steps: '}, 'Enter a position', 1, defaultPositionMM);

            if(isempty(userPositionMM))
                return
            end 

            positionXY = [str2double(userPositionMM{1}),str2double(userPositionMM{2})]/1000;
            radius = str2double(userPositionMM{3})/1000;

            app.drawCircle(positionXY, radius, str2double(userPositionMM{4}));
        end

        % Value changed function: SetCurrentZAsFlourButton
        function setFlourZ(app, event)
            robotVariables = app.robotSL.getpos();
            app.flourD3 = robotVariables(3);
        end

        % Value changed function: TouchUntouchFlourButton
        function touchUntouchFloar(app, event)
            app.lowerOrRiseToWrite();
        end

        % Button pushed function: DrawGridButton
        function drawGridButton(app, event)
            defaultPositionMM = {'29.424', '90.911', '50'};
            userPositionMM = inputdlg({'x (mm): ', 'y (mm): ', 'width/height (mm): '}, 'Enter a position', 1, defaultPositionMM);

            if(isempty(userPositionMM))
                return
            end 

            positionXY = [str2double(userPositionMM{1}),str2double(userPositionMM{2})]/1000;
            width_height = str2double(userPositionMM{3})/1000;

            app.drawGrid(positionXY(1), positionXY(2), width_height, width_height);


        end

        % Button pushed function: DrawCrossButton
        function drawCrossButton(app, event)
            defaultPositionMM = {'54.424', '115.911', '7', '4'};
            userPositionMM = inputdlg({'x (mm): ', 'y (mm): ', 'length (mm): ', 'steps: '}, 'Enter a position', 1, defaultPositionMM);

            if(isempty(userPositionMM))
                return
            end 

            positionXY = [str2double(userPositionMM{1}),str2double(userPositionMM{2})]/1000;
            radius = str2double(userPositionMM{3})/1000;

            app.drawCross(positionXY, radius, str2double(userPositionMM{4}));
        end

        % Button pushed function: DrawGridWithCrossesfixedButton
        function drawGridWithCrossesButton(app, event)
            
            positionXY = [58.848/2, 181.822/2]/1000;
            
            app.drawGrid(positionXY(1), positionXY(2), 50/1000, 50/1000);
            for j = 0:2
            for i = 0:2
                x = positionXY(1) + 50/1000/3/2+50/1000/3*i;
                y = positionXY(2) + 50/1000/3/2+50/1000/3*j;
                app.drawCross([x,y], 7/1000, 4);

            end
            end
        end

        % Button pushed function: DrawGridWithCirclesfixedButton
        function drawGridWithCirclesButton(app, event)
                        
            positionXY = [58.848/2, 181.822/2]/1000;
            
            app.drawGrid(positionXY(1), positionXY(2), 50/1000, 50/1000);
            for j = 0:2
            for i = 0:2
                x = positionXY(1) + 50/1000/3/2+50/1000/3*i;
                y = positionXY(2) + 50/1000/3/2+50/1000/3*j;
                app.drawCircle([x,y], 5/1000, 50);

            end
            end
        end

        % Button pushed function: DrawWorkspaceEnvelopeButton
        function drawEnvelope(app, event)
            app.Gauge.Value = 0;  
          
            Nstep = 10;
            counter = 0;

            for d3 = linspace(app.jointsLimits(3, 1), app.jointsLimits(3,2), Nstep)
                for t1 = linspace(app.jointsLimits(1, 1), app.jointsLimits(1,2), Nstep)
                    for t2 = linspace(app.jointsLimits(2, 1), app.jointsLimits(2,2), Nstep)
                    
                        transformation = app.robotSL.fkine([t1, t2, d3]);
                        plot_sphere(transformation.t, .002 ,'c', .5);

                        counter = counter +1;
                        app.Gauge.Value = app.Gauge.Value + 100*counter/(Nstep*Nstep*Nstep);
                    end
                end       
            end

        end

        % Button pushed function: PlayTicTacToeButton
        function startGame(app, event)
            
            %check if arduino is already connected
            if(isempty(app.device))
                msgbox("Arduino is not connected.");
            end

            game = TicTacToe([29.424, 90.911], 50, app.robotSL, app.device, app.servo1, app.servo2, app.servo3, app.motorCalibration,...
                app.startingVariables, app.flourD3, app.DHparametersAndType(1, 2), app.DHparametersAndType(2, 2));
            game.board
        end

        % Close request function: RobotControlModelGUI
        function RobotControlModelGUICloseRequest(app, event)
            %when app is closed, close all windows
            close("all")
            clc
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RobotControlModelGUI = uifigure('Visible', 'off');
            app.RobotControlModelGUI.Name = 'Robot Control Model';
            app.RobotControlModelGUI.CloseRequestFcn = createCallbackFcn(app, @RobotControlModelGUICloseRequest, true);
            app.GridLayout = uigridlayout(app.RobotControlModelGUI);
            app.GridLayout.Tag = 'axes1';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.AddRevoluteJointButton = uibutton(app.GridLayout5, 'push');
            app.AddRevoluteJointButton.ButtonPushedFcn = createCallbackFcn(app, @addRevoluteJoint, true);
            app.AddRevoluteJointButton.Text = 'Add Revolute Joint';
            app.AddPrismaticJointButton = uibutton(app.GridLayout5, 'push');
            app.AddPrismaticJointButton.ButtonPushedFcn = createCallbackFcn(app, @addPrismaticJoint, true);
            app.AddPrismaticJointButton.Text = 'Add Prismatic Joint';
            app.RemoveAllJointsButton = uibutton(app.GridLayout5, 'push');
            app.RemoveAllJointsButton.ButtonPushedFcn = createCallbackFcn(app, @removeAllJoints, true);
            app.RemoveAllJointsButton.Text = 'Remove All Joints';
            app.AutoconfigureButton = uibutton(app.GridLayout5, 'push');
            app.AutoconfigureButton.ButtonPushedFcn = createCallbackFcn(app, @autoconfigureRobot, true);
            app.AutoconfigureButton.Text = 'Autoconfigure';
            app.SelectJointListBox = uilistbox(app.GridLayout5);
            app.SelectJointListBox.Items = {};
            app.SelectJointListBox.ValueChangedFcn = createCallbackFcn(app, @jointSelected, true);
            app.SelectJointListBox.Value = {};
            app.CreateModelButton = uibutton(app.GridLayout5, 'push');
            app.CreateModelButton.ButtonPushedFcn = createCallbackFcn(app, @createRobot, true);
            app.CreateModelButton.Text = 'Create Model';
            app.GridLayout10 = uigridlayout(app.GridLayout5);
            app.BaseXEditFieldLabel = uilabel(app.GridLayout10);
            app.BaseXEditFieldLabel.Text = 'Base X';
            app.BaseXEditField = uieditfield(app.GridLayout10, 'numeric');
            app.BaseXEditField.ValueChangedFcn = createCallbackFcn(app, @editBaseX, true);
            app.mmLabel = uilabel(app.GridLayout10);
            app.mmLabel.Text = 'mm';
            app.GridLayout11 = uigridlayout(app.GridLayout5);
            app.BaseYEditFieldLabel = uilabel(app.GridLayout11);
            app.BaseYEditFieldLabel.Text = 'Base Y';
            app.BaseYEditField = uieditfield(app.GridLayout11, 'numeric');
            app.BaseYEditField.ValueChangedFcn = createCallbackFcn(app, @editBaseY, true);
            app.mmLabel_2 = uilabel(app.GridLayout11);
            app.mmLabel_2.Text = 'mm';
            app.GridLayout13 = uigridlayout(app.GridLayout5);
            app.OptimizeButton = uibutton(app.GridLayout13, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @optimizeDhParameters, true);
            app.OptimizeButton.Text = 'Optimize';
            app.OptimizationStatusLamp = uilamp(app.GridLayout13);
            app.OptimizationStatusLabel = uilabel(app.GridLayout13);
            app.OptimizationStatusLabel.Text = 'Done';
            app.GridLayout20 = uigridlayout(app.GridLayout5);
            app.SelectJointListBoxLabel = uilabel(app.GridLayout20);
            app.SelectJointListBoxLabel.Text = 'Select Joint';
            app.Gauge = uigauge(app.GridLayout20, 'linear');
            app.TabGroup = uitabgroup(app.GridLayout);
            app.JointDhParametersTab = uitab(app.TabGroup);
            app.JointDhParametersTab.Title = 'Joint Dh Parameters';
            app.GridLayout6 = uigridlayout(app.JointDhParametersTab);
            app.JointAngledegreesPanel = uipanel(app.GridLayout6);
            app.JointAngledegreesPanel.Title = 'Joint Angle (degrees)';
            app.thetaEditFieldLabel = uilabel(app.JointAngledegreesPanel);
            app.thetaEditFieldLabel.Text = 'theta';
            app.thetaEditField = uieditfield(app.JointAngledegreesPanel, 'numeric');
            app.theta_minEditFieldLabel = uilabel(app.JointAngledegreesPanel);
            app.theta_minEditFieldLabel.Text = 'theta_min';
            app.theta_minEditField = uieditfield(app.JointAngledegreesPanel, 'numeric');
            app.theta_maxEditFieldLabel = uilabel(app.JointAngledegreesPanel);
            app.theta_maxEditFieldLabel.Text = 'theta_max';
            app.theta_maxEditField = uieditfield(app.JointAngledegreesPanel, 'numeric');
            app.LinkOffsetmmPanel = uipanel(app.GridLayout6);
            app.LinkOffsetmmPanel.Title = 'Link Offset (mm)';
            app.dEditField = uieditfield(app.LinkOffsetmmPanel, 'numeric');
            app.dEditFieldLabel = uilabel(app.LinkOffsetmmPanel);
            app.dEditFieldLabel.Text = 'd';
            app.d_minEditFieldLabel = uilabel(app.LinkOffsetmmPanel);
            app.d_minEditFieldLabel.Text = 'd_min';
            app.d_minEditField = uieditfield(app.LinkOffsetmmPanel, 'numeric');
            app.d_maxEditFieldLabel = uilabel(app.LinkOffsetmmPanel);
            app.d_maxEditFieldLabel.Text = 'd_max';
            app.d_maxEditField = uieditfield(app.LinkOffsetmmPanel, 'numeric');
            app.LinkLengthmmPanel = uipanel(app.GridLayout6);
            app.LinkLengthmmPanel.Title = 'Link Length (mm)';
            app.aEditFieldLabel = uilabel(app.LinkLengthmmPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.LinkLengthmmPanel, 'numeric');
            app.LinkTwistdegreesPanel = uipanel(app.GridLayout6);
            app.LinkTwistdegreesPanel.Title = 'Link Twist (degrees)';
            app.alphaEditField_2Label = uilabel(app.LinkTwistdegreesPanel);
            app.alphaEditField_2Label.Text = 'alpha';
            app.alphaEditField = uieditfield(app.LinkTwistdegreesPanel, 'numeric');
            app.AssignParametersButton = uibutton(app.GridLayout6, 'state');
            app.AssignParametersButton.ValueChangedFcn = createCallbackFcn(app, @setDhParameters, true);
            app.AssignParametersButton.Text = 'Assign Parameters';
            app.JointOffsetdegreesPanel = uipanel(app.GridLayout6);
            app.JointOffsetdegreesPanel.Title = 'Joint Offset (degrees)';
            app.alphaEditField_2Label_2 = uilabel(app.JointOffsetdegreesPanel);
            app.alphaEditField_2Label_2.Text = 'offset';
            app.offsetEditField = uieditfield(app.JointOffsetdegreesPanel, 'numeric');
            app.JointControlTab = uitab(app.TabGroup);
            app.JointControlTab.Title = 'Joint Control';
            app.GridLayout12 = uigridlayout(app.JointControlTab);
            app.Joint1UnitLabel = uilabel(app.GridLayout12);
            app.Joint1UnitLabel.Text = '';
            app.Joint1ControlLabel = uilabel(app.GridLayout12);
            app.Joint1ControlLabel.Text = '';
            app.Joint2ControlLabel = uilabel(app.GridLayout12);
            app.Joint2ControlLabel.Text = '';
            app.Joint2UnitLabel = uilabel(app.GridLayout12);
            app.Joint2UnitLabel.Text = '';
            app.Joint3ControlLabel = uilabel(app.GridLayout12);
            app.Joint3ControlLabel.Text = '';
            app.Joint3UnitLabel = uilabel(app.GridLayout12);
            app.Joint3UnitLabel.Text = '';
            app.Joint4ControlLabel = uilabel(app.GridLayout12);
            app.Joint4ControlLabel.Text = '';
            app.Joint4UnitLabel = uilabel(app.GridLayout12);
            app.Joint4UnitLabel.Text = '';
            app.Joint5ControlLabel = uilabel(app.GridLayout12);
            app.Joint5ControlLabel.Text = '';
            app.Joint5UnitLabel = uilabel(app.GridLayout12);
            app.Joint5UnitLabel.Text = '';
            app.Joint2SliderLabel = uilabel(app.GridLayout12);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2ControlSlider = uislider(app.GridLayout12);
            app.Joint2ControlSlider.ValueChangedFcn = createCallbackFcn(app, @updateJointsConfiguration, true);
            app.Joint2ControlSlider.Enable = 'off';
            app.Joint3SliderLabel = uilabel(app.GridLayout12);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3ControlSlider = uislider(app.GridLayout12);
            app.Joint3ControlSlider.ValueChangedFcn = createCallbackFcn(app, @updateJointsConfiguration, true);
            app.Joint3ControlSlider.Enable = 'off';
            app.Joint4SliderLabel = uilabel(app.GridLayout12);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4ControlSlider = uislider(app.GridLayout12);
            app.Joint4ControlSlider.ValueChangedFcn = createCallbackFcn(app, @updateJointsConfiguration, true);
            app.Joint4ControlSlider.Enable = 'off';
            app.Joint5SliderLabel = uilabel(app.GridLayout12);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5ControlSlider = uislider(app.GridLayout12);
            app.Joint5ControlSlider.ValueChangedFcn = createCallbackFcn(app, @updateJointsConfiguration, true);
            app.Joint5ControlSlider.Enable = 'off';
            app.Joint1SliderLabel = uilabel(app.GridLayout12);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1ControlSlider = uislider(app.GridLayout12);
            app.Joint1ControlSlider.ValueChangedFcn = createCallbackFcn(app, @updateJointsConfiguration, true);
            app.Joint1ControlSlider.Enable = 'off';
            app.SelectPoseDropDownLabel = uilabel(app.GridLayout12);
            app.SelectPoseDropDownLabel.Text = 'Select Pose ';
            app.SelectPoseDropDown = uidropdown(app.GridLayout12);
            app.SelectPoseDropDown.Items = {'Cap Pose', 'Starting Pose'};
            app.SelectPoseDropDown.Value = 'Cap Pose';
            app.MoveToPoseButton = uibutton(app.GridLayout12, 'push');
            app.MoveToPoseButton.ButtonPushedFcn = createCallbackFcn(app, @setRobotToSelectedPose, true);
            app.MoveToPoseButton.Enable = 'off';
            app.MoveToPoseButton.Text = 'Move To Pose';
            app.GameClassRobotOnlyTab = uitab(app.TabGroup);
            app.GameClassRobotOnlyTab.Title = 'Game (Class Robot Only)';
            app.GridLayout21 = uigridlayout(app.GameClassRobotOnlyTab);
            app.PlayTicTacToeButton = uibutton(app.GridLayout21, 'push');
            app.PlayTicTacToeButton.ButtonPushedFcn = createCallbackFcn(app, @startGame, true);
            app.PlayTicTacToeButton.Text = 'Play Tic-Tac-Toe';
            app.XButton = uibutton(app.GridLayout21, 'push');
            app.XButton.Text = 'X';
            app.XButton_2 = uibutton(app.GridLayout21, 'push');
            app.XButton_2.Text = 'X';
            app.XButton_9 = uibutton(app.GridLayout21, 'push');
            app.XButton_9.Text = 'X';
            app.XButton_3 = uibutton(app.GridLayout21, 'push');
            app.XButton_3.Text = 'X';
            app.XButton_4 = uibutton(app.GridLayout21, 'push');
            app.XButton_4.Text = 'X';
            app.XButton_5 = uibutton(app.GridLayout21, 'push');
            app.XButton_5.Text = 'X';
            app.XButton_6 = uibutton(app.GridLayout21, 'push');
            app.XButton_6.Text = 'X';
            app.XButton_7 = uibutton(app.GridLayout21, 'push');
            app.XButton_7.Text = 'X';
            app.XButton_8 = uibutton(app.GridLayout21, 'push');
            app.XButton_8.Text = 'X';
            app.XButton_10 = uibutton(app.GridLayout21, 'push');
            app.XButton_10.Text = 'X';
            app.OButton = uibutton(app.GridLayout21, 'push');
            app.OButton.Text = 'O';
            app.MovetotheCellLabel = uilabel(app.GridLayout21);
            app.MovetotheCellLabel.Text = 'Move to the Cell ';
            app.PlayTurnLabel = uilabel(app.GridLayout21);
            app.PlayTurnLabel.Text = 'Play Turn';
            app.MovementsClassRobotOnlyTab = uitab(app.TabGroup);
            app.MovementsClassRobotOnlyTab.Title = 'Movements (Class Robot Only)';
            app.GridLayout18 = uigridlayout(app.MovementsClassRobotOnlyTab);
            app.DrawCircleButton = uibutton(app.GridLayout18, 'push');
            app.DrawCircleButton.ButtonPushedFcn = createCallbackFcn(app, @drawCircleButton, true);
            app.DrawCircleButton.Text = 'Draw Circle';
            app.DrawCrossButton = uibutton(app.GridLayout18, 'push');
            app.DrawCrossButton.ButtonPushedFcn = createCallbackFcn(app, @drawCrossButton, true);
            app.DrawCrossButton.Text = 'Draw Cross ';
            app.DrawGridButton = uibutton(app.GridLayout18, 'push');
            app.DrawGridButton.ButtonPushedFcn = createCallbackFcn(app, @drawGridButton, true);
            app.DrawGridButton.Text = 'Draw Grid';
            app.DrawGridWithCrossesfixedButton = uibutton(app.GridLayout18, 'push');
            app.DrawGridWithCrossesfixedButton.ButtonPushedFcn = createCallbackFcn(app, @drawGridWithCrossesButton, true);
            app.DrawGridWithCrossesfixedButton.Text = 'Draw Grid With Crosses (fixed)';
            app.DrawGridWithCirclesfixedButton = uibutton(app.GridLayout18, 'push');
            app.DrawGridWithCirclesfixedButton.ButtonPushedFcn = createCallbackFcn(app, @drawGridWithCirclesButton, true);
            app.DrawGridWithCirclesfixedButton.Text = 'Draw Grid With Circles (fixed)';
            app.DrawWorkspaceEnvelopeButton = uibutton(app.GridLayout18, 'push');
            app.DrawWorkspaceEnvelopeButton.ButtonPushedFcn = createCallbackFcn(app, @drawEnvelope, true);
            app.DrawWorkspaceEnvelopeButton.Text = 'Draw Workspace Envelope';
            app.EndEffectorPanel = uipanel(app.GridLayout);
            app.EndEffectorPanel.Title = 'End-Effector Panel';
            app.GridLayout8 = uigridlayout(app.EndEffectorPanel);
            app.ControlPanel = uipanel(app.GridLayout8);
            app.ControlPanel.Title = 'Control';
            app.GridLayout4 = uigridlayout(app.ControlPanel);
            app.ForwardButton = uibutton(app.GridLayout4, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @MoveEEForward, true);
            app.ForwardButton.Text = 'Forward';
            app.LeftButton = uibutton(app.GridLayout4, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @moveEELeft, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.GridLayout4, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @moveEERight, true);
            app.RightButton.Text = 'Right';
            app.BackwardButton = uibutton(app.GridLayout4, 'push');
            app.BackwardButton.ButtonPushedFcn = createCallbackFcn(app, @moveEEBackwards, true);
            app.BackwardButton.Text = 'Backward';
            app.LowerButton = uibutton(app.GridLayout4, 'push');
            app.LowerButton.ButtonPushedFcn = createCallbackFcn(app, @moveEEDown, true);
            app.LowerButton.Text = 'Lower';
            app.RiseButton = uibutton(app.GridLayout4, 'push');
            app.RiseButton.ButtonPushedFcn = createCallbackFcn(app, @moveEEUp, true);
            app.RiseButton.Text = 'Rise';
            app.StepEditFieldLabel = uilabel(app.GridLayout4);
            app.StepEditFieldLabel.Text = 'Step';
            app.StepEditField = uieditfield(app.GridLayout4, 'text');
            app.StepEditField.Value = '1';
            app.mmLabel_3 = uilabel(app.GridLayout4);
            app.mmLabel_3.Text = 'mm';
            app.PositionWithRespecttoBoardPanel = uipanel(app.GridLayout8);
            app.PositionWithRespecttoBoardPanel.Title = 'Position With Respect to Board';
            app.GridLayout9 = uigridlayout(app.PositionWithRespecttoBoardPanel);
            app.XLabel = uilabel(app.GridLayout9);
            app.XLabel.Text = 'X:';
            app.YLabel = uilabel(app.GridLayout9);
            app.YLabel.Text = 'Y:';
            app.ZLabel = uilabel(app.GridLayout9);
            app.ZLabel.Text = 'Z:';
            app.EExLabel = uilabel(app.GridLayout9);
            app.EExLabel.Text = '';
            app.EEyLabel = uilabel(app.GridLayout9);
            app.EEyLabel.Text = '';
            app.EEzLabel = uilabel(app.GridLayout9);
            app.EEzLabel.Text = '';
            app.mmLabel_4 = uilabel(app.GridLayout9);
            app.mmLabel_4.Text = 'mm';
            app.mmLabel_5 = uilabel(app.GridLayout9);
            app.mmLabel_5.Text = 'mm';
            app.mmLabel_6 = uilabel(app.GridLayout9);
            app.mmLabel_6.Text = 'mm';
            app.PhysicalRobotControlClassRobotOnlyPanel = uipanel(app.GridLayout8);
            app.PhysicalRobotControlClassRobotOnlyPanel.Title = 'Physical Robot Control (Class Robot Only)';
            app.GridLayout17 = uigridlayout(app.PhysicalRobotControlClassRobotOnlyPanel);
            app.ConnectArduinoButton = uibutton(app.GridLayout17, 'push');
            app.ConnectArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @connectArduino, true);
            app.ConnectArduinoButton.Text = 'Connect Arduino';
            app.ArduinoConnectionLamp = uilamp(app.GridLayout17);
            app.SynchronizeLabel = uilabel(app.GridLayout17);
            app.SynchronizeLabel.Text = 'Synchronize';
            app.SynchronizeSwitch = uiswitch(app.GridLayout17, 'slider');
            app.SynchronizeSwitch.Items = {'No', 'Yes'};
            app.SynchronizeSwitch.ValueChangedFcn = createCallbackFcn(app, @changeRobotSyncranation, true);
            app.SynchronizeSwitch.Value = 'Yes';
            app.SyncRobottoModelButton = uibutton(app.GridLayout17, 'push');
            app.SyncRobottoModelButton.ButtonPushedFcn = createCallbackFcn(app, @syncRobotToModel, true);
            app.SyncRobottoModelButton.Enable = 'off';
            app.SyncRobottoModelButton.Text = 'Sync Robot to Model';
            app.SyncModeltoRobotButton = uibutton(app.GridLayout17, 'push');
            app.SyncModeltoRobotButton.ButtonPushedFcn = createCallbackFcn(app, @syncModelToRobot, true);
            app.SyncModeltoRobotButton.Enable = 'off';
            app.SyncModeltoRobotButton.Text = 'Sync Model to Robot';
            app.SetCurrentZAsFlourButton = uibutton(app.GridLayout17, 'state');
            app.SetCurrentZAsFlourButton.ValueChangedFcn = createCallbackFcn(app, @setFlourZ, true);
            app.SetCurrentZAsFlourButton.Text = 'Set Current Z As Flour';
            app.TouchUntouchFlourButton = uibutton(app.GridLayout17, 'state');
            app.TouchUntouchFlourButton.ValueChangedFcn = createCallbackFcn(app, @touchUntouchFloar, true);
            app.TouchUntouchFlourButton.Text = 'Touch/Untouch Flour';
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.DHParametersTable = uitable(app.GridLayout7);
            app.DHParametersTable.ColumnName = {'i'; 'Theta_i (rad)'; 'd_i (m)'; 'a_i (m)'; 'alpha_i (rad)'; 'sigma_i'};
            app.DHParametersTable.RowName = {};
            app.ConfigurationShownTableLabel = uilabel(app.GridLayout7);
            app.ConfigurationShownTableLabel.Text = 'Configuration Shown Table';
            app.ContextMenu = uicontextmenu(app.RobotControlModelGUI);
            app.Menu_2 = uimenu(app.ContextMenu);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu);
            app.Menu2_2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.GridLayout.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.RobotControlModelGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotControlModel_11

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RobotControlModelGUI)

            % Execute the startup function
            runStartupFcn(app, @startupHandler)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RobotControlModelGUI)
        end
    end
end