classdef RobotControlModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        EndEffectorPanel              matlab.ui.container.Panel
        GridLayout8                   matlab.ui.container.GridLayout
        PositionPanel                 matlab.ui.container.Panel
        GridLayout9                   matlab.ui.container.GridLayout
        EEzLabel                      matlab.ui.control.Label
        EEyLabel                      matlab.ui.control.Label
        EExLabel                      matlab.ui.control.Label
        ZLabel                        matlab.ui.control.Label
        YLabel                        matlab.ui.control.Label
        XLabel                        matlab.ui.control.Label
        ControlPanel                  matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        DownButton                    matlab.ui.control.Button
        RightButton                   matlab.ui.control.Button
        LeftButton                    matlab.ui.control.Button
        UpButton                      matlab.ui.control.Button
        GridLayout7                   matlab.ui.container.GridLayout
        ConfigurationShownTableLabel  matlab.ui.control.Label
        DHParametersTable             matlab.ui.control.Table
        TabGroup                      matlab.ui.container.TabGroup
        JointDhParametersTab          matlab.ui.container.Tab
        GridLayout6                   matlab.ui.container.GridLayout
        AssignParametersButton        matlab.ui.control.StateButton
        LinkTwistdegreesPanel         matlab.ui.container.Panel
        alphaEditField                matlab.ui.control.NumericEditField
        alphaEditField_2Label         matlab.ui.control.Label
        LinkLengthmmPanel             matlab.ui.container.Panel
        aEditField                    matlab.ui.control.NumericEditField
        aEditFieldLabel               matlab.ui.control.Label
        LinkOffsetmmPanel             matlab.ui.container.Panel
        d_maxEditFieldLabel           matlab.ui.control.Label
        d_maxEditField                matlab.ui.control.NumericEditField
        d_minEditField                matlab.ui.control.NumericEditField
        d_minEditFieldLabel           matlab.ui.control.Label
        dEditFieldLabel               matlab.ui.control.Label
        dEditField                    matlab.ui.control.NumericEditField
        JointAngledegreesPanel        matlab.ui.container.Panel
        theta_maxEditField            matlab.ui.control.NumericEditField
        theta_maxEditFieldLabel       matlab.ui.control.Label
        theta_minEditField            matlab.ui.control.NumericEditField
        theta_minEditFieldLabel       matlab.ui.control.Label
        thetaEditField                matlab.ui.control.NumericEditField
        thetaEditFieldLabel           matlab.ui.control.Label
        JointControlTab               matlab.ui.container.Tab
        Joint1ControlSlider           matlab.ui.control.Slider
        Joint1SliderLabel             matlab.ui.control.Label
        Joint5UnitLabel               matlab.ui.control.Label
        Joint4UnitLabel               matlab.ui.control.Label
        Joint3UnitLabel               matlab.ui.control.Label
        Joint2UnitLabel               matlab.ui.control.Label
        Joint1UnitLabel               matlab.ui.control.Label
        Joint5ControlLabel            matlab.ui.control.Label
        Joint4ControlLabel            matlab.ui.control.Label
        Joint3ControlLabel            matlab.ui.control.Label
        Joint2ControlLabel            matlab.ui.control.Label
        Joint1ControlLabel            matlab.ui.control.Label
        Joint5ControlSlider           matlab.ui.control.Slider
        Joint5SliderLabel             matlab.ui.control.Label
        Joint4ControlSlider           matlab.ui.control.Slider
        Joint4SliderLabel             matlab.ui.control.Label
        Joint3ControlSlider           matlab.ui.control.Slider
        Joint3SliderLabel             matlab.ui.control.Label
        Joint2ControlSlider           matlab.ui.control.Slider
        Joint2SliderLabel             matlab.ui.control.Label
        GridLayout5                   matlab.ui.container.GridLayout
        CreateRobotButton             matlab.ui.control.Button
        SelectJointListBox            matlab.ui.control.ListBox
        SelectJointListBoxLabel       matlab.ui.control.Label
        AutoconfigureButton           matlab.ui.control.Button
        RemoveAllJointsButton         matlab.ui.control.Button
        AddPrismaticJointButton       matlab.ui.control.Button
        AddRevoluteJointButton        matlab.ui.control.Button
        axis1                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        %initialize robot Serial Link object
        robotSL =[]

        %initialize DH parameters for each link. Row index represents joint index.
        %First column represents joint angle (rad),
        %Second column represents link offset (m),
        %Third column represents link length (m),
        %Fourth column represents link twist (rad),
        %Fifth colum represents joint type (1-prismatic, 0-revolute)
        DHparametersAndType = []

        %initialize matrix that represents limits of matched index joint.
        %First column represents min value, while second column represents
        %max value. For prismatic joints, range of d parameter is
        %represented, for revolute, renged of theta parameter is
        %represented.
        jointsLimits = []

        endEffectorXYZofBase = [];
        

    end

    methods (Access = private)

        function updateDHtable(app)
            app.DHParametersTable.Data = [[1:size(app.DHparametersAndType, 1)]',app.DHparametersAndType];
        end


        function updateSelectJointList(app)
            counter = 1;
            for t = app.DHparametersAndType(:, 5)'
                if(t == 1)
                    jointName = strcat('P',num2str(counter));
                else
                    jointName = strcat('R',num2str(counter));
                end
                app.SelectJointListBox.Items(counter) = {jointName};
                counter = counter + 1;
            end
        end

        function configureJointControlTab(app)
            %group joint control elements in the arrays for easy access
            jointControlSliders = [app.Joint1ControlSlider, app.Joint2ControlSlider,app.Joint3ControlSlider, app.Joint4ControlSlider,app.Joint5ControlSlider];
            jointControlLabels = [app.Joint1ControlLabel, app.Joint2ControlLabel,app.Joint3ControlLabel, app.Joint4ControlLabel,app.Joint5ControlLabel];
            jointUnitLabels = [app.Joint1UnitLabel, app.Joint2UnitLabel,app.Joint3UnitLabel, app.Joint4UnitLabel,app.Joint5UnitLabel];


            %go though each joint index
            for j = 1:size(app.DHparametersAndType, 1)
                jointType = app.DHparametersAndType(j, 5);
                if(jointType == 0)
                    jointControlSliders(j).Limits = app.jointsLimits(j, :) * 180/pi;
                    jointControlSliders(j).Value = app.DHparametersAndType(j, 1) * 180/pi;
                    jointUnitLabels(j).Text = 'deg';
                else
                    jointControlSliders(j).Limits = app.jointsLimits(j, :) * 1000;
                    jointControlSliders(j).Value = app.DHparametersAndType(j, 2) * 1000;
                    jointUnitLabels(j).Text = 'mm';
                end

                jointControlLabels(j).Text = num2str(jointControlSliders(j).Value);
                jointControlSliders(j).Enable = true;

            end
        end
        
        function results = getArrayOfJointVariables(app)

            %assuing all joints are revolute, assign in row form
            results = app.DHparametersAndType(:, 1)';

            %modify starting configuration for prismatic joints
            %fo though each joint index, is joint is prismatic, fix
            %starting configuration at the joint index
            for j = 1:size(app.DHparametersAndType, 1)
                if(app.DHparametersAndType(j, 5) == 1)
                    results(j) = app.DHparametersAndType(j, 2);
                end
            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddRevoluteJointButton
        function addRevoluteJoint(app, event)
            numberOfexistingJoints = size(app.DHparametersAndType, 1);
            newJointName = strcat('R',num2str(numberOfexistingJoints+1));

            app.SelectJointListBox.Items(end + 1) = {newJointName};
            app.DHparametersAndType =[app.DHparametersAndType; 0,0,0,0,0];
            app.jointsLimits = [app.jointsLimits;
                                 [0, 2*pi]];
            app.updateDHtable();
        end

        % Button pushed function: AddPrismaticJointButton
        function addPrismaticJoint(app, event)
            numberOfexistingJoints = size(app.DHparametersAndType, 1);
            newJointName = strcat('P',num2str(numberOfexistingJoints+1));

            app.SelectJointListBox.Items(end + 1) = {newJointName};
            app.DHparametersAndType =[app.DHparametersAndType; 0,0,0,0,1];
            app.jointsLimits = [app.jointsLimits;
                                 [0, .01]];
            app.updateDHtable();
        end

        % Button pushed function: RemoveAllJointsButton
        function removeAllJoints(app, event)
            app.SelectJointListBox.Items = {};
            app.jointsLimits = [];
            app.DHparametersAndType = [];
            app.updateDHtable();
        end

        % Value changed function: SelectJointListBox
        function jointSelected(app, event)
            selectedJointName = app.SelectJointListBox.Value;
            selectedJointIndex = str2num(selectedJointName(2));

            app.thetaEditField.Value = app.DHparametersAndType(selectedJointIndex, 1)*180/pi;
            app.dEditField.Value = app.DHparametersAndType(selectedJointIndex, 2)*1000;
            app.aEditField.Value = app.DHparametersAndType(selectedJointIndex, 3)*1000;
            app.alphaEditField.Value = app.DHparametersAndType(selectedJointIndex, 4)*180/pi;
            jointType = app.DHparametersAndType(selectedJointIndex, 5); %where 1 is prismatic and 0 is revolute

            if(jointType == 0)
                app.d_minEditField.Editable = false;
                app.d_maxEditField.Editable = false;
                app.d_minEditField.Value = 0;
                app.d_maxEditField.Value = 0;

                app.theta_minEditField.Editable = true;
                app.theta_maxEditField.Editable = true;
                app.theta_minEditField.Value = app.jointsLimits(selectedJointIndex, 1)*180/pi;
                app.theta_maxEditField.Value = app.jointsLimits(selectedJointIndex, 2)*180/pi;
            else
                app.theta_minEditField.Editable = false;
                app.theta_maxEditField.Editable = false;
                app.theta_minEditField.Value = 0;
                app.theta_maxEditField.Value = 0;

                app.d_minEditField.Editable = true;
                app.d_maxEditField.Editable = true;
                app.d_minEditField.Value = app.jointsLimits(selectedJointIndex, 1)*1000;
                app.d_maxEditField.Value = app.jointsLimits(selectedJointIndex, 2)*1000;
            end


        end

        % Value changed function: AssignParametersButton
        function setDhParameters(app, event)

            selectedJointIndex = str2num(app.SelectJointListBox.Value(2));
            
            new_theta = app.thetaEditField.Value*pi/180;
            new_d = app.dEditField.Value/1000;
            new_a = app.aEditField.Value/1000;
            new_alpha = app.alphaEditField.Value*pi/180;

            %read limits
            jointType = app.DHparametersAndType(selectedJointIndex, 5);
            if(jointType == 0)
                jointLimit = [app.theta_minEditField.Value, app.theta_maxEditField.Value]*pi/180;
            else
                jointLimit = [app.d_minEditField.Value, app.d_maxEditField.Value]/1000;
            end

            app.jointsLimits(selectedJointIndex, :) = jointLimit;

            app.DHparametersAndType(selectedJointIndex, 1:end-1) = [new_theta, new_d, new_a, new_alpha];
            app.updateDHtable();

        end

        % Button pushed function: CreateRobotButton
        function createRobot(app, event)
            if(size(app.DHparametersAndType, 1)>0)
                for l = 1:size(app.DHparametersAndType, 1)
                    L(l) = Link(app.DHparametersAndType(l, :));
                    L(l).qlim = app.jointsLimits(l, :);
                end

                app.robotSL = SerialLink(L, 'name', 'robot');

                startingRobotConfiguration = app.getArrayOfJointVariables();

                
                app.robotSL.plot(startingRobotConfiguration, 'workspace', [-.05, .05, -.05, .05, 0, .05 ], 'floorlevel',0);
                app.robotSL.A(1:size(app.DHparametersAndType, 1), startingRobotConfiguration).t

                app.configureJointControlTab();
                app.TabGroup.SelectedTab = app.JointControlTab;

            end
        end

        % Button pushed function: AutoconfigureButton
        function autoconfigureRobot(app, event)
            %assign predifined Dh prameters
            %First column represents joint angle (rad),
            %Second column represents link offset (m),
            %Third column represents link length (m),
            %Fourth column represents link twist (rad),
            %Fifth colum represents joint type (1-prismatic, 0-revolute)
            app.DHparametersAndType = [pi/6, .01, .01, 0, 0;
                                       pi/6, .01, .01, pi, 0;
                                       0,    0, 0, 0, 1];

            app.jointsLimits = [0, pi;0, pi;0,.01];

            app.updateDHtable();
            app.updateSelectJointList();

        end

        % Value changed function: Joint1ControlSlider, 
        % ...and 4 other components
        function updateJointsConfiguration(app, event)
            %group joint control elements in the arrays for easy access
            jointControlSliders = [app.Joint1ControlSlider, app.Joint2ControlSlider,app.Joint3ControlSlider, app.Joint4ControlSlider,app.Joint5ControlSlider];
            jointControlLabels = [app.Joint1ControlLabel, app.Joint2ControlLabel,app.Joint3ControlLabel, app.Joint4ControlLabel,app.Joint5ControlLabel];
            
            %update DH parameters matrix from new slider positions
            %go through joint indeces
            for j = 1:size(app.DHparametersAndType, 1)
                jointType = app.DHparametersAndType(j, 5);
                jointControlLabels(j).Text = num2str(jointControlSliders(j).Value);

                %get new slider value and convert it to SI units
                if(jointType == 0)
                    app.DHparametersAndType(j, 1) = jointControlSliders(j).Value*pi/180;
                else
                    app.DHparametersAndType(j, 2) = jointControlSliders(j).Value/1000;
                end
            end

            app.updateDHtable();
            robotConfiguration = app.getArrayOfJointVariables();

            app.robotSL.animate(robotConfiguration);
            app.robotSL.A(1:size(app.DHparametersAndType, 1), robotConfiguration)
            app.robotSL.A(1:size(app.DHparametersAndType, 1), robotConfiguration).t


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout.Tag = 'axes1';
            app.axis1 = uiaxes(app.GridLayout);
            xlabel(app.axis1, 'X')
            ylabel(app.axis1, 'Y')
            zlabel(app.axis1, 'Z')
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.AddRevoluteJointButton = uibutton(app.GridLayout5, 'push');
            app.AddRevoluteJointButton.ButtonPushedFcn = createCallbackFcn(app, @addRevoluteJoint, true);
            app.AddRevoluteJointButton.Text = 'Add Revolute Joint';
            app.AddPrismaticJointButton = uibutton(app.GridLayout5, 'push');
            app.AddPrismaticJointButton.ButtonPushedFcn = createCallbackFcn(app, @addPrismaticJoint, true);
            app.AddPrismaticJointButton.Text = 'Add Prismatic Joint';
            app.RemoveAllJointsButton = uibutton(app.GridLayout5, 'push');
            app.RemoveAllJointsButton.ButtonPushedFcn = createCallbackFcn(app, @removeAllJoints, true);
            app.RemoveAllJointsButton.Text = 'Remove All Joints';
            app.AutoconfigureButton = uibutton(app.GridLayout5, 'push');
            app.AutoconfigureButton.ButtonPushedFcn = createCallbackFcn(app, @autoconfigureRobot, true);
            app.AutoconfigureButton.Text = 'Autoconfigure';
            app.SelectJointListBoxLabel = uilabel(app.GridLayout5);
            app.SelectJointListBoxLabel.Text = 'Select Joint';
            app.SelectJointListBox = uilistbox(app.GridLayout5);
            app.SelectJointListBox.Items = {};
            app.SelectJointListBox.ValueChangedFcn = createCallbackFcn(app, @jointSelected, true);
            app.SelectJointListBox.Value = {};
            app.CreateRobotButton = uibutton(app.GridLayout5, 'push');
            app.CreateRobotButton.ButtonPushedFcn = createCallbackFcn(app, @createRobot, true);
            app.CreateRobotButton.Text = 'Create Robot';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.JointDhParametersTab = uitab(app.TabGroup);
            app.JointDhParametersTab.Title = 'Joint Dh Parameters';
            app.GridLayout6 = uigridlayout(app.JointDhParametersTab);
            app.JointAngledegreesPanel = uipanel(app.GridLayout6);
            app.JointAngledegreesPanel.Title = 'Joint Angle (degrees)';
            app.thetaEditFieldLabel = uilabel(app.JointAngledegreesPanel);
            app.thetaEditFieldLabel.Text = 'theta';
            app.thetaEditField = uieditfield(app.JointAngledegreesPanel, 'numeric');
            app.theta_minEditFieldLabel = uilabel(app.JointAngledegreesPanel);
            app.theta_minEditFieldLabel.Text = 'theta_min';
            app.theta_minEditField = uieditfield(app.JointAngledegreesPanel, 'numeric');
            app.theta_maxEditFieldLabel = uilabel(app.JointAngledegreesPanel);
            app.theta_maxEditFieldLabel.Text = 'theta_max';
            app.theta_maxEditField = uieditfield(app.JointAngledegreesPanel, 'numeric');
            app.LinkOffsetmmPanel = uipanel(app.GridLayout6);
            app.LinkOffsetmmPanel.Title = 'Link Offset (mm)';
            app.dEditField = uieditfield(app.LinkOffsetmmPanel, 'numeric');
            app.dEditFieldLabel = uilabel(app.LinkOffsetmmPanel);
            app.dEditFieldLabel.Text = 'd';
            app.d_minEditFieldLabel = uilabel(app.LinkOffsetmmPanel);
            app.d_minEditFieldLabel.Text = 'd_min';
            app.d_minEditField = uieditfield(app.LinkOffsetmmPanel, 'numeric');
            app.d_maxEditField = uieditfield(app.LinkOffsetmmPanel, 'numeric');
            app.d_maxEditFieldLabel = uilabel(app.LinkOffsetmmPanel);
            app.d_maxEditFieldLabel.Text = 'd_max';
            app.LinkLengthmmPanel = uipanel(app.GridLayout6);
            app.LinkLengthmmPanel.Title = 'Link Length (mm)';
            app.aEditFieldLabel = uilabel(app.LinkLengthmmPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.LinkLengthmmPanel, 'numeric');
            app.LinkTwistdegreesPanel = uipanel(app.GridLayout6);
            app.LinkTwistdegreesPanel.Title = 'Link Twist (degrees)';
            app.alphaEditField_2Label = uilabel(app.LinkTwistdegreesPanel);
            app.alphaEditField_2Label.Text = 'alpha';
            app.alphaEditField = uieditfield(app.LinkTwistdegreesPanel, 'numeric');
            app.AssignParametersButton = uibutton(app.GridLayout6, 'state');
            app.AssignParametersButton.ValueChangedFcn = createCallbackFcn(app, @setDhParameters, true);
            app.AssignParametersButton.Text = 'Assign Parameters';
            app.JointControlTab = uitab(app.TabGroup);
            app.JointControlTab.Title = 'Joint Control';
            app.Joint2SliderLabel = uilabel(app.JointControlTab);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2ControlSlider = uislider(app.JointControlTab);
            app.Joint2ControlSlider.ValueChangedFcn = createCallbackFcn(app, @updateJointsConfiguration, true);
            app.Joint2ControlSlider.Enable = 'off';
            app.Joint3SliderLabel = uilabel(app.JointControlTab);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3ControlSlider = uislider(app.JointControlTab);
            app.Joint3ControlSlider.ValueChangedFcn = createCallbackFcn(app, @updateJointsConfiguration, true);
            app.Joint3ControlSlider.Enable = 'off';
            app.Joint4SliderLabel = uilabel(app.JointControlTab);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4ControlSlider = uislider(app.JointControlTab);
            app.Joint4ControlSlider.ValueChangedFcn = createCallbackFcn(app, @updateJointsConfiguration, true);
            app.Joint4ControlSlider.Enable = 'off';
            app.Joint5SliderLabel = uilabel(app.JointControlTab);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5ControlSlider = uislider(app.JointControlTab);
            app.Joint5ControlSlider.ValueChangedFcn = createCallbackFcn(app, @updateJointsConfiguration, true);
            app.Joint5ControlSlider.Enable = 'off';
            app.Joint1ControlLabel = uilabel(app.JointControlTab);
            app.Joint1ControlLabel.Text = '';
            app.Joint2ControlLabel = uilabel(app.JointControlTab);
            app.Joint2ControlLabel.Text = '';
            app.Joint3ControlLabel = uilabel(app.JointControlTab);
            app.Joint3ControlLabel.Text = '';
            app.Joint4ControlLabel = uilabel(app.JointControlTab);
            app.Joint4ControlLabel.Text = '';
            app.Joint5ControlLabel = uilabel(app.JointControlTab);
            app.Joint5ControlLabel.Text = '';
            app.Joint1UnitLabel = uilabel(app.JointControlTab);
            app.Joint1UnitLabel.Text = '';
            app.Joint2UnitLabel = uilabel(app.JointControlTab);
            app.Joint2UnitLabel.Text = '';
            app.Joint3UnitLabel = uilabel(app.JointControlTab);
            app.Joint3UnitLabel.Text = '';
            app.Joint4UnitLabel = uilabel(app.JointControlTab);
            app.Joint4UnitLabel.Text = '';
            app.Joint5UnitLabel = uilabel(app.JointControlTab);
            app.Joint5UnitLabel.Text = '';
            app.Joint1SliderLabel = uilabel(app.JointControlTab);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1ControlSlider = uislider(app.JointControlTab);
            app.Joint1ControlSlider.ValueChangedFcn = createCallbackFcn(app, @updateJointsConfiguration, true);
            app.Joint1ControlSlider.Enable = 'off';
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.DHParametersTable = uitable(app.GridLayout7);
            app.DHParametersTable.ColumnName = {'i'; 'Theta_i (rad)'; 'd_i (m)'; 'a_i (m)'; 'alpha_i (rad)'; 'sigma_i'};
            app.DHParametersTable.RowName = {};
            app.ConfigurationShownTableLabel = uilabel(app.GridLayout7);
            app.ConfigurationShownTableLabel.Text = 'Configuration Shown Table';
            app.EndEffectorPanel = uipanel(app.GridLayout);
            app.EndEffectorPanel.Title = 'End-Effector Panel';
            app.GridLayout8 = uigridlayout(app.EndEffectorPanel);
            app.ControlPanel = uipanel(app.GridLayout8);
            app.ControlPanel.Title = 'Control';
            app.GridLayout4 = uigridlayout(app.ControlPanel);
            app.UpButton = uibutton(app.GridLayout4, 'push');
            app.UpButton.Text = 'Up';
            app.LeftButton = uibutton(app.GridLayout4, 'push');
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.GridLayout4, 'push');
            app.RightButton.Text = 'Right';
            app.DownButton = uibutton(app.GridLayout4, 'push');
            app.DownButton.Text = 'Down';
            app.PositionPanel = uipanel(app.GridLayout8);
            app.PositionPanel.Title = 'Position';
            app.GridLayout9 = uigridlayout(app.PositionPanel);
            app.XLabel = uilabel(app.GridLayout9);
            app.XLabel.Text = 'X:';
            app.YLabel = uilabel(app.GridLayout9);
            app.YLabel.Text = 'Y:';
            app.ZLabel = uilabel(app.GridLayout9);
            app.ZLabel.Text = 'Z:';
            app.EExLabel = uilabel(app.GridLayout9);
            app.EExLabel.Text = '';
            app.EEyLabel = uilabel(app.GridLayout9);
            app.EEyLabel.Text = '';
            app.EEzLabel = uilabel(app.GridLayout9);
            app.EEzLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotControlModel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end